classdef AMB_FSL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        EditField                   matlab.ui.control.NumericEditField
        Label_9                     matlab.ui.control.Label
        EditField_3                 matlab.ui.control.NumericEditField
        EditField_3Label_2          matlab.ui.control.Label
        EditField_2                 matlab.ui.control.EditField
        Label_7                     matlab.ui.control.Label
        Button                      matlab.ui.control.Button
        pointEditField              matlab.ui.control.NumericEditField
        pointEditFieldLabel         matlab.ui.control.Label
        TDMSRealTimeData_EditField  matlab.ui.control.NumericEditField
        TDMSRealTimeData_EditFieldLabel  matlab.ui.control.Label
        Image_auto_result           matlab.ui.control.Image
        Switch_auto                 matlab.ui.control.Switch
        PHMLabel_2                  matlab.ui.control.Label
        Lamp_auto                   matlab.ui.control.Lamp
        Lamp_PHM                    matlab.ui.control.Lamp
        Panel_PHM                   matlab.ui.container.Panel
        Text_PHM2                   matlab.ui.control.TextArea
        Label_10                    matlab.ui.control.Label
        Text_PHM4                   matlab.ui.control.TextArea
        Label_8                     matlab.ui.control.Label
        Image_branch_size_right     matlab.ui.control.Image
        Image_branch_size_left      matlab.ui.control.Image
        DropDown_size               matlab.ui.control.DropDown
        Label_6                     matlab.ui.control.Label
        DropDown_branch             matlab.ui.control.DropDown
        Label_5                     matlab.ui.control.Label
        Image_PHM_result            matlab.ui.control.Image
        Text_PHM3                   matlab.ui.control.TextArea
        Label_4                     matlab.ui.control.Label
        Text_PHM1                   matlab.ui.control.TextArea
        Label_3                     matlab.ui.control.Label
        Switch_PHM                  matlab.ui.control.Switch
        PHMLabel                    matlab.ui.control.Label
        pushbutton_ExportData       matlab.ui.control.Button
        Spinner_2                   matlab.ui.control.Spinner
        Spinner_1                   matlab.ui.control.Spinner
        text8_2                     matlab.ui.control.Label
        Lamp_Slider2                matlab.ui.control.Lamp
        Lamp_Slider1                matlab.ui.control.Lamp
        slider_Time1                matlab.ui.control.Slider
        slider_Time2                matlab.ui.control.Slider
        Panel_Info                  matlab.ui.container.Panel
        Info5Label                  matlab.ui.control.Label
        Info4Label                  matlab.ui.control.Label
        Info3Label                  matlab.ui.control.Label
        Info2Label                  matlab.ui.control.Label
        Info1Label                  matlab.ui.control.Label
        Info5TextArea               matlab.ui.control.TextArea
        Info4TextArea               matlab.ui.control.TextArea
        Info3TextArea               matlab.ui.control.TextArea
        Info2TextArea               matlab.ui.control.TextArea
        Info1TextArea               matlab.ui.control.TextArea
        Panel_2                     matlab.ui.container.Panel
        Chn                         matlab.ui.control.ListBox
        Panel                       matlab.ui.container.Panel
        DL                          matlab.ui.container.ButtonGroup
        sLabel                      matlab.ui.control.Label
        dataTime                    matlab.ui.control.NumericEditField
        r3                          matlab.ui.control.RadioButton
        r2                          matlab.ui.control.RadioButton
        r1                          matlab.ui.control.RadioButton
        uipanel4                    matlab.ui.container.Panel
        Lamp                        matlab.ui.control.Lamp
        Label                       matlab.ui.control.Label
        checkbox_Sensor             matlab.ui.control.CheckBox
        text_Z                      matlab.ui.control.Label
        text_X2Y2                   matlab.ui.control.Label
        text_X1Y1                   matlab.ui.control.Label
        uipanel1                    matlab.ui.container.Panel
        DA                          matlab.ui.container.ButtonGroup
        Lamp_PopPic                 matlab.ui.control.Lamp
        Switch_PopPic               matlab.ui.control.Switch
        Label_2                     matlab.ui.control.Label
        Button_Vt                   matlab.ui.control.RadioButton
        Button_At                   matlab.ui.control.RadioButton
        Button_FFT                  matlab.ui.control.RadioButton
        pushbutton_WaterFall        matlab.ui.control.Button
        pushbutton_Setting          matlab.ui.control.Button
        text_FileName               matlab.ui.control.Label
        pushbutton_Open             matlab.ui.control.Button
        edit_TimeV1                 matlab.ui.control.EditField
        edit_TimeV2                 matlab.ui.control.EditField
        text_Setting                matlab.ui.control.Label
        UIAxes_2                    matlab.ui.control.UIAxes
        axes3                       matlab.ui.control.UIAxes
        axes2                       matlab.ui.control.UIAxes
        axes1                       matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CApp % Description
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %添加自己需要用到的属性变量（用于全局传递）
        status_damage_value_temp = 0;
        status_value_temp = 0;
        damage_value_temp = 0;
        
        %记录正常、疑似故障、故障的百分数据点
        auto_normal = []; 
        auto_abnormal = [];
        auto_ae_normal = []; 
        auto_ae_abnormal = [];


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    end


    methods (Access = private)
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
        %添加自己用到的函数

        function plt_Positioning_label(app,axesHandle,position_temp,color_temp) 

            cla(axesHandle);
                    
            % 定义 定位标签矩形的坐标
            x = position_temp - 0.02;
            y = 0;
            width = 0.01;
            height = 1;
            
            % 创建矩形
            rectanglePatch = patch(axesHandle, [x, x+width, x+width, x, x], [y, y, y+height, y+height, y], color_temp);
            
            % 设置矩形的透明度
            alpha(rectanglePatch, 0.30);
            
            % 设置坐标区的限制，确保矩形与底层的数据轴相对应
            axis(axesHandle, [0, 1, 0, 1]);
        end


 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  


        function states = DataStates(app)
            %数据设置, 1为全部数据，2为当前TDMS，3为指定数据
            if app.r1.Value == 1
                dataFlag = 1;
            end
            if app.r2.Value == 1
                dataFlag = 2;
            end
            if app.r3.Value == 1
                dataFlag = 3;
            end
            
            %通道设置
            values = app.Chn.Value;
            items = app.Chn.Items;
            
            chnFlag = [];
            for iV = 1:length(values)
                [~,ty] = find(strcmp(items, values{iV}));
                chnFlag = [chnFlag ty];
            end
            
            %数据分析模式
            asyFlag = 0;
            if app.Button_FFT.Value == 1
                asyFlag = 2;
            end
            if app.Button_At.Value == 1
                asyFlag = 1;
            end
            if app.Button_Vt.Value == 1
                asyFlag = 3;
            end

            states{1} = dataFlag;  %数据形式，1为全部数据，2为当前TDMS，3为指定数据
            states{2} = chnFlag;   %通道形式，e.g. [1,2,3]
            states{3} = asyFlag;   %分析形式，2为fft，1为时域图，3为转速-时间
        end
        
        %设置setting界面数目
        function SetData(app)
            %显示设置
            maxLimit = 5;
            zRange = 0.8;
            numPt = 8000;
            
            %通道设置
            chnOrder = [4 5 2 3 6];
            
            speedChn = 17;
            FFTRange = 1000;
                
            %2022.4.14 瀑布图设置
            WFSt = 1;
            WFEd = 2000;
            WFIt = 1;
            
            %2022.4.14速度曲线点数
            SpeedPts = 100; 
            SpeedSwitch = 'Off';  %选择转速通道为'On'，选择计算转速为'Off'；
            
            %2022.4.17振动
            Vib = '幅值';
            Disp = '多曲线一图';

            %2023.12.26增加对TXT文件的支持
            TXT_channeNames = 'EXC,X1,Y1,Z1,X2,Y2,PX1,NX1,PY1,NY1,PZ,NZ,PX2,NX2,PY2,NY2,time,speed,rotor_angle'; % 数据通道的定义
            TXT_channel_data_scaling = '1/2048,1/32,1/32,1/32,1/32,1/32,1/256,1/256,1/256,1/256,1/256,1/256,1/256,1/256,1/256,1/256,1,1,1'; % 数据通道的缩放

            tdmsInfo = struct('numPt',numPt,'maxLimit',maxLimit,'zRange',zRange,...
                'chnOrder',chnOrder,'speedChn',speedChn,'FFTRange',FFTRange,...
                'WFSt',WFSt,'WFEd',WFEd,'WFIt',WFIt,...
                'SpeedPts',SpeedPts,'SpeedSwitch',SpeedSwitch,...
                'Vib',Vib,'Disp',Disp,...
                'TXT_channeNames',TXT_channeNames,'TXT_channel_data_scaling',TXT_channel_data_scaling);
            % 获取当前运行的 .m 文件的路径
            currentPath = fileparts(mfilename('fullpath'));
            
            % 指定相对路径的文件夹
            relativeFolderPath = 'config';
            
            % 构建完整的文件夹路径
            folderPath = fullfile(currentPath, relativeFolderPath);
            
            % 如果文件夹不存在，则创建
            if ~exist(folderPath, 'dir')
                mkdir(folderPath);
            end
            
            % 构建完整的文件路径
            filePath = fullfile(folderPath, 'tdmsInfo_config.mat');
            
            % 保存 tdmsInfo 结构为 .mat 文件（如果文件已经存在则覆盖）
            save(filePath, 'tdmsInfo', '-mat', '-v7.3');
            
            app.pushbutton_Setting.UserData = tdmsInfo;
         
        end
                 
        function FFTCurve(app,chnFlag)
            chnNum = length(chnFlag);  %通道数目，单个通道在UIAxes显示，多个通道时第一个选中通道在UIAxes显示，多个通道分别在Figures
                
            tdms3 = app.pushbutton_Open.UserData;
            tdmsInfo = app.pushbutton_Setting.UserData;
            FFTRange = tdmsInfo.FFTRange;   
            numPt = tdmsInfo.numPt;
               
            %获取时域数据
%             value = app.slider_Time1.Value;
            %2022.4.20 增加两个slider
            if app.Lamp_Slider1.Color == [0 1 0]
                value = app.slider_Time1.Value;
            else
                value = app.slider_Time2.Value;
            end
            
            
            [fileLocation,ptLocation] = tdms3.Location(value);        
            [data, ~] = tdms3.GetSlice(fileLocation,chnFlag,ptLocation,numPt,2);              
            
            %FFT
            [fx,Ax] = FFTx(data(1,:),tdms3.sampling,FFTRange);
            
            %显示
            plot(app.UIAxes,fx,Ax)
            app.UIAxes.Title.String = [tdms3.channelNames{chnFlag(1)} '-FFT'];
            app.UIAxes.XLabel.String = '频率（Hz）';
            app.UIAxes.YLabel.String = '幅值'; 
            app.UIAxes.XLim = [0,FFTRange];
            app.UIAxes.YLim = [0,max(Ax)]; 
            legend(app.UIAxes,'hide');
            
            %6. 信息板显示
            app.Info1Label.Text = '第1频率/Hz';
            app.Info2Label.Text = '第2频率/Hz';
            app.Info3Label.Text = '第3频率/Hz';
            app.Info4Label.Text = '第4频率/Hz';
            app.Info5Label.Text = '第5频率/Hz';
            
            [pks,locs] = findpeaks(Ax,'MinPeakDistance',15,'NPeaks',5,'SortStr','descend');
            app.Info1TextArea.Value = [num2str(round(fx(locs(1)))) '/' num2str(pks(1),'%2f')];
            app.Info2TextArea.Value = [num2str(round(fx(locs(2)))) '/' num2str(pks(2),'%2f')];
            app.Info3TextArea.Value = [num2str(round(fx(locs(3)))) '/' num2str(pks(3),'%2f')];
            app.Info4TextArea.Value = [num2str(round(fx(locs(4)))) '/' num2str(pks(4),'%2f')];
            app.Info5TextArea.Value = [num2str(round(fx(locs(5)))) '/' num2str(pks(5),'%2f')];
            
            %画多图
            %2023.5.18 弹出图片
            value = app.Switch_PopPic.Value;            
            if chnNum > 1 || isequal(value,'On')
                for iC = 1:chnNum
                    [fx,Ax] = FFTx(data(iC,:),tdms3.sampling,FFTRange);
                    figure;
                    plot(fx,Ax);
                    title([tdms3.channelNames{chnFlag(iC)} '-FFT']);
                    grid on
                    xlabel('频率（Hz）');ylabel('幅值');
                end
            end           
            
        end
             
        function VibCurve(app,chnFlag)
            chnNum = length(chnFlag);  %通道数目，单个通道在UIAxes显示，多个通道时第一个选中通道在UIAxes显示，多个通道分别在Figures
                
            tdms3 = app.pushbutton_Open.UserData;   
            tdmsInfo = app.pushbutton_Setting.UserData;
            
            numPt = tdmsInfo.numPt;           
            Vib = tdmsInfo.Vib;       %振动类型，幅值/平均值/峰峰值
            Disp = tdmsInfo.Disp;     %显示类型，单图/多图      
            SpeedPts = tdmsInfo.SpeedPts;  %速度曲线点数     
            chnOrder = tdmsInfo.chnOrder;  %振动通道顺序
        
            %1.获取数据块
            W1 = app.slider_Time1.UserData;
            W2 = app.slider_Time2.UserData;
            data = tdms3.WLocation(W1,W2,chnFlag);
            %如果传感器标定开启，对振动通道进行传感器标定
            if app.checkbox_Sensor.Value == 1
                for iC = 1:chnNum
                    tempIC = find(chnOrder == chnFlag(iC));
                    if isempty(tempIC) == 0
                        data(iC,:) = tdms3.DataAfterSensor2(data(iC,:),tempIC);
                    end
                end
            end

            %2.三种不同类型的振动
            if strcmp(Vib,'幅值') == 1
                y = data;
            end
            if strcmp(Vib,'平均值') == 1
                tempFilter = ones(1,numPt)/numPt;
                y = filter(tempFilter,1,data,[],2);  %平均滤波
            end
            if strcmp(Vib,'峰峰值') == 1
                dataLength = size(data,2);
                iterNum = floor(dataLength/SpeedPts);
                y = zeros(chnNum,SpeedPts);
                for iT = 1:SpeedPts
                    st = (iT-1)*iterNum+1;
                    ed = st + numPt - 1;
                    if ed > dataLength
                        break
                    end
                    y(:,iT) = max(data(:,st:ed),[],2) - min(data(:,st:ed),[],2);
                end                  
            end
            
            %3.显示，只画第1个通道的数据
            x = [1:length(y)]/length(y);
            plot(app.UIAxes,x,y(1,:));   %只画第1个通道在区域上
            app.UIAxes.Title.String = [tdms3.channelNames{chnFlag(1)} '-振动曲线'];
            
            %4.坐标区域设置
            app.UIAxes.XLabel.String = '相对时间';
            app.UIAxes.YLabel.String = [Vib '/V']; 
            app.UIAxes.XLim = [0,1];       
            app.UIAxes.YLim = [min(y(1,:)) - 0.2*(abs(min(y(1,:)))), max(y(1,:)) + 0.2*(abs(max(y(1,:))))];
                            
            
            %弹出图，2023.5.18
            value = app.Switch_PopPic.Value;
            if isequal(value,'On')
                if strcmp(Disp,'单曲线')
                    for iC = 1:chnNum
                        figure;
                        plot(x,y(iC,:));
                        title([tdms3.channelNames{chnFlag(iC)} '-振动曲线']);
                        grid on
                        xlabel('相对时间');
                        ylabel([Vib '/V']);
                    end                   
                else            
                    legendTitle = cell(1,chnNum);
                    figure;
                    for iC = 1:chnNum
                        plot(x,y(iC,:),'DisplayName',tdms3.channelNames{chnFlag(iC)});
                        legendTitle{1,iC} = tdms3.channelNames{chnFlag(iC)};
                        hold on
                    end
                    grid on;
                    legend(legendTitle);
                    xlim([0,1]);
                    ylim([min([0,min(y(:))])*1.2,max(y(:))*1.2]);
                end
            end
            
            
            %5.画多图
            if chnNum > 1 
                if strcmp(Disp,'单曲线') && isequal(value,'Off')
                    for iC = 1:chnNum
                        figure;
                        plot(x,y(iC,:));
                        title([tdms3.channelNames{chnFlag(iC)} '-振动曲线']);
                        grid on
                        xlabel('相对时间');
                        ylabel([Vib '/V']);
                    end                   
                else            
                    legendTitle = cell(1,chnNum);
                    for iC = 1:chnNum
                        plot(app.UIAxes,x,y(iC,:),'DisplayName',tdms3.channelNames{chnFlag(iC)});
                        legendTitle{1,iC} = tdms3.channelNames{chnFlag(iC)};
                        hold on
                    end
                    legend(app.UIAxes,legendTitle);
                    app.UIAxes.XLim = [0,1];
                    app.UIAxes.YLim = [min([0,min(y(:))])*1.2,max(y(:))*1.2];
                end
            end 
            
            %6. 信息板显示
            app.Info1Label.Text = '振动类型/传感器';
            app.Info2Label.Text = '通道';
            app.Info3Label.Text = '平均值';
            app.Info4Label.Text = '最大值';
            app.Info5Label.Text = '最小值';
                
            if app.checkbox_Sensor.Value == 1
                sensorStr = '标定后';
            else
                sensorStr = '未标定';
            end
            app.Info1TextArea.Value = [Vib '/' sensorStr];
            app.Info2TextArea.Value = tdms3.channelNames{chnFlag(1)};
            app.Info3TextArea.Value = num2str(mean(y(1,:)));
            app.Info4TextArea.Value = num2str(max(y(1,:)));
            app.Info5TextArea.Value = num2str(min(y(1,:)));
        end

    end
    
    methods (Access = public)
        function SetGlobalPos(app,states)
            value1 = app.slider_Time1.Value;
            value2 = app.slider_Time2.Value;
            W1 = app.slider_Time1.UserData;
            W2 = app.slider_Time2.UserData;
            
            if states{1} == 1
                %将全部TDMS的广域值设置为0,1
                app.slider_Time1.UserData = 0;
                app.slider_Time2.UserData = 1;
               
                app.slider_Time1.Value = W1 + value1*(W2-W1);
                app.slider_Time2.Value = W1 + value2*(W2-W1);
                %[0 1 W1 + value1*(W2-W1) W1 + value2*(W2-W1)]
            end
            
            if states{1} == 2
                tdms3 = app.pushbutton_Open.UserData;
              
                tempValue1 = W1 + value1*(W2-W1);  %滑块1的全局位置              
                [fileLocation1,ptLocation1] = tdms3.Location(tempValue1);                
                tempValue2 = W1 + value2*(W2-W1);  %滑块1的全局位置  
                [fileLocation2,ptLocation2] = tdms3.Location(tempValue2);
                
                if app.Lamp_Slider1.Color == [0 1 0]
                    assignin("app.")
                    [a,b] = tdms3.TDMSLocation(fileLocation1);               
                else
                    [a,b] = tdms3.TDMSLocation(fileLocation2);               
                end    
                
                %滑块1
                if tempValue1 >= a && tempValue1 <= b
                    app.slider_Time1.Value = (tempValue1 - a) / (b - a);
                elseif tempValue1 < a
                    app.slider_Time1.Value = 0;
                else
                    app.slider_Time1.Value = 1;
                end
                %滑块2
                if tempValue2 >= a && tempValue2 <= b
                    app.slider_Time2.Value = (tempValue2 - a) / (b - a);
                elseif tempValue2 < a
                    app.slider_Time2.Value = 0;
                else
                    app.slider_Time2.Value = 1;
                end                
                
                app.slider_Time1.UserData = a;
                app.slider_Time2.UserData = b;                 
            end
            
            if states{1} == 3
                %从状态1跳到状态
                app.slider_Time1.UserData = W1 + value1*(W2-W1);
                app.slider_Time2.UserData = W1 + value2*(W2-W1);  
                
                %在状态3中变化
                app.slider_Time1.Value = 0;
                app.slider_Time2.Value = 1;                            
            end
            
            app.Spinner_1.Value = app.slider_Time1.Value;
            app.Spinner_2.Value = app.slider_Time2.Value;
        end
      
        function SpeedCurve(app)
             
            tdms3 = app.pushbutton_Open.UserData;
            tdmsInfo = app.pushbutton_Setting.UserData;
 
            w1 = app.slider_Time1.UserData;
            w2 = app.slider_Time2.UserData;
            totalTime = tdms3.totalTime * (w2 - w1);
                
            %计算转速
            if strcmp(tdmsInfo.SpeedSwitch,'Off')     
                chnOrder = tdmsInfo.chnOrder;
                numPt = tdmsInfo.numPt;
            
                speedPts = tdmsInfo.SpeedPts;
                fMax = zeros(1,speedPts);
                h = waitbar(0,'正在计算，请稍候!');
                

                for value = 1:speedPts
    %                 value
                    value1 = w1 + value/speedPts*(w2 - w1);
                    [fileLocation,ptLocation] = tdms3.Location(value1);
                    %[fileLocation,ptLocation] = tdms3.Location(value/speedPts);
                    [data, ~] = tdms3.GetSlice(fileLocation,chnOrder,ptLocation,numPt,2);  
                    
                    data = tdms3.DataAfterSensor(data);
                    fMax(value) = tdms3.FFTPlot2(data,tdms3.sampling,0);   
                    waitbar(value/speedPts,h,['已经计算' num2str(value) '/' num2str(speedPts) '个点！']);
                end
                close(h)
                
                x = [1:speedPts]/speedPts;
                y = fMax;
                
                app.Info1TextArea.Value = '计算转速';
            %读取转速
            else      
                if isempty(tdmsInfo.speedChn) == 1
                    msgbox('需要在设置转速通道！');
                else
                    %y = tdms3.GetData([1:tdms3.fileNum],tdmsInfo.speedChn);
                    y = tdms3.WLocation(w1,w2,tdmsInfo.speedChn)
                    x = [1:length(y)]/length(y); 
                    
                    speedPts = tdmsInfo.SpeedPts;
                    numPt = floor(length(y)/speedPts);
                    
                    y = y(1:numPt:end);
                    x = x(1:numPt:end);
                end
                
                app.Info1TextArea.Value = '转速传感器';
            end
            
            [a,b] = max(y);   %求最大转速
            
            plot(app.UIAxes,x*totalTime,y*60)
            hold on
            plot(app.UIAxes,b*totalTime/length(y),a*60,'o')
            app.UIAxes.Title.String = '转速曲线'; 
            app.UIAxes.XLabel.String = '时间/s';
            app.UIAxes.YLabel.String = '转速/rpm';
            
            app.UIAxes.XLim = [0,totalTime];
            app.UIAxes.YLim = [0,max(y)*1.2*60];
            
            %2023.5.18 
            value = app.Switch_PopPic.Value;
            if isequal(value,'On')
                figure;
                plot(x*totalTime,y*60)
                hold on
                grid on
                plot(b*totalTime/length(y),a*60,'o')
                title('转速曲线'); 
                xlabel('时间/s');
                ylabel('转速/rpm')                
                xlim([0,totalTime]);
                ylim([0,max(y)*1.2*60]);
            end
            
            
            %更新信息栏
            app.Info1Label.Text = '转速类型';
            app.Info2Label.Text = '最大转速/Hz';
            app.Info3Label.Text = '最大转速位置';    
            
                   
            app.Info2TextArea.Value = num2str(a);   
            app.Info3TextArea.Value = num2str(b/length(y));
        end
        
        function data = DrawOrbit2(app)
                %给tdmsInfo类赋值，显示和通道设置
                tdmsInfo = app.pushbutton_Setting.UserData;
                maxLimit = tdmsInfo.maxLimit;
                zRange = tdmsInfo.zRange;
                chnOrder = tdmsInfo.chnOrder;
                numPt = tdmsInfo.numPt;
                speedChn = tdmsInfo.speedChn;
                SpeedSwitch = tdmsInfo.SpeedSwitch;
                
                %设置坐标
                %未读入数据
                if isempty(app.pushbutton_Open.UserData) == 1
                    data = [];
                %已经读入数据
                else
                    %两个重要的类，数据类和数据信息类
                    tdms3 = app.pushbutton_Open.UserData;
            
                    %S1：获取slider位置，定位数据块
                    %2022.4.20 增加两个slider
                    value1 = app.slider_Time1.Value;
                    value2 = app.slider_Time2.Value;

                    %2022.7.5增加
                    W1 = app.slider_Time1.UserData;
                    W2 = app.slider_Time2.UserData;
                    
                    Wv1 = W1 + value1*(W2 - W1);
                    Wv2 = W1 + value2*(W2 - W1);

                    if app.Lamp_Slider1.Color == [0 1 0]
                        value = Wv1;
                    else
                        value = Wv2;
                    end
                    
                    totalTime = tdms3.totalTime;
                    [fileLocation,ptLocation] = tdms3.Location(value);
                    [data, ~] = tdms3.GetSlice(fileLocation,chnOrder,ptLocation,numPt,2);
                    
%                     itervalTime = abs(value2 - value1)*totalTime;
                    itervalTime = abs(Wv1 - Wv2)*totalTime;
                    app.dataTime.Value = itervalTime;
            
                    %结合传感器标定数据，转换原始数据，如果通道顺序不是X1，Y1，X2，Y2，Z，则转换的数据无效
                    data = tdms3.DataAfterSensor(data);
                   
                    %S2：轴心轨迹画图
                    plot(app.axes1,data(1,:),data(2,:),'.');
                    plot(app.axes2,data(3,:),data(4,:),'.');
                    plot(app.axes3,[0:length(data(5,:))-1]/tdms3.sampling,data(5,:));
            
                    set(app.axes1,'Color',[0.94 0.94 0.94])
                    set(app.axes2,'Color',[0.94 0.94 0.94])
                    set(app.axes3,'Color',[0.94 0.94 0.94])
                       
                    %S3：文件名控件更新
                    matName = tdms3.fileNames{1,fileLocation};
                    nameStr = [matName '   ' num2str(ptLocation) '/' num2str(tdms3.filePoints(fileLocation)) ' ' num2str(fileLocation) '/' num2str(tdms3.fileNum)];
                    app.text_FileName.Text = nameStr;
            
                    %S4：时间控件更新
                    current_time = value*totalTime;
                    %app.edit_TimeV1.Value = [num2str(current_time,'%.2f') '/' num2str(totalTime)];
            
%                     %S5：转速控件更新
%                     %1. 判断转速通道数据是否存在, 如果不存在，就关闭转速通道，2022.4.21修改
%                     if sum(ismember(tdms3.channelNames,'ChannelSpeedVal')) == 0 && strcmp(SpeedSwitch,'On') == 1
%                         msgbox('转速通道设置错误！');
% 
%                         app.Lamp.Color = [1,1,1];
%                         tdmsInfo.SpeedSwitch = 'Off';
%                         app.pushbutton_Setting.UserData = tdmsInfo;
%                         SpeedSwitch = 'Off';
%                     end

                    %2. 是否使用转速通道
                    if isempty(speedChn) == 0 && strcmp(SpeedSwitch,'On')           
                        %2022.4.15 使用转速传感器
                        [data2,~] = tdms3.GetSlice(fileLocation,speedChn,ptLocation,numPt,2);
                        fMax = mean(data2);
                        
                        %转速指示灯亮起
                        app.Lamp.Color = [0 1 0];
                    else
                        %可以根据上述通道进行表决，或者是根据指定转速通道20200601
                        fMax = tdms3.FFTPlot2(data,tdms3.sampling,0);
                        
                        %转速指示灯灭掉
                        app.Lamp.Color = [1 1 1];
                    end
                    %app.edit_TimeV2.Value = [num2str(fMax,'%.2f') '/' num2str(round(60*fMax),'%d')];
            
                    dispInfo = [num2str(current_time,'%.2f') '/' num2str(totalTime) '---' num2str(fMax,'%.2f') '/' num2str(round(60*fMax),'%d')];
                    if app.Lamp_Slider1.Color == [0 1 0]
                        app.edit_TimeV1.Value = dispInfo;
                    else
                        app.edit_TimeV2.Value = dispInfo;
                    end
                    
                    %S6：通道控件更新
                    %显示坐标轴对应的数据通道20200812
                    app.text_X1Y1.Text = [tdms3.channelNames{chnOrder(1)} '---' tdms3.channelNames{chnOrder(2)}];
                    app.text_X2Y2.Text = [tdms3.channelNames{chnOrder(3)} '---' tdms3.channelNames{chnOrder(4)}];
                    app.text_Z.Text = [tdms3.channelNames{chnOrder(5)}];                    


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                    
                    
                    
                    %S7: 故障诊断功能20230918right
                    value = app.Switch_PHM.Value;
                    value_Switch_auto = app.Switch_auto.Value;

                    if isequal(value,'On') && isequal(value_Switch_auto,'Off')
                    %临时保存当前8000个数据点，并调用Python进行异常检测
                    save('data_temp\processed_data_temp.mat', 'data');
                    app.status_damage_value_temp = matpy1();
                    app.status_value_temp = double(app.status_damage_value_temp(1));
                    app.damage_value_temp = double(app.status_damage_value_temp(2));

                    % UI界面显示检测结果
                        if app.status_value_temp == 0 
                            app.Text_PHM1.Value = '正常（小于疑似阈值）';
                            app.Text_PHM2.Value = '无';
                            app.Text_PHM3.Value = '无';
                            app.Text_PHM4.Value = '无';
                            app.Image_branch_size_left.Visible = "off";
                            app.Image_branch_size_right.Visible = "off";
                            app.Image_PHM_result.Visible = 'on';
                            app.Image_PHM_result.ImageSource = fullfile('img\', '运行正常_temp.png');
                            
                        elseif app.status_value_temp == 1.3 
                            app.Text_PHM1.Value = '异常（超警戒阈值）';
                            app.Text_PHM2.Value = '1端、2端';
                            app.Text_PHM3.Value = '碰磨';
                            app.Text_PHM4.Value = num2str(app.damage_value_temp);
                            app.Image_branch_size_left.Visible = "off";
                            app.Image_branch_size_right.Visible = "off";
                            app.Image_PHM_result.Visible = 'on';
                            app.Image_PHM_result.ImageSource = fullfile('img\', '异常_temp.png');
                        elseif app.status_value_temp == 1.1 
                            app.Text_PHM1.Value = '异常（超警戒阈值）';
                            app.Text_PHM2.Value = '1端';
                            app.Text_PHM3.Value = '碰磨';
                            app.Text_PHM4.Value = num2str(app.damage_value_temp);
                            app.Image_branch_size_left.Visible = "off";
                            app.Image_branch_size_right.Visible = "off";
                            app.Image_PHM_result.Visible = 'on';
                            app.Image_PHM_result.ImageSource = fullfile('img\', '异常_temp.png');
                        elseif app.status_value_temp == 1.2 
                            app.Text_PHM1.Value = '异常（超警戒阈值）';
                            app.Text_PHM2.Value = '2端';
                            app.Text_PHM3.Value = '碰磨';
                            app.Text_PHM4.Value = num2str(app.damage_value_temp);
                            app.Image_branch_size_left.Visible = "off";
                            app.Image_branch_size_right.Visible = "off";
                            app.Image_PHM_result.Visible = 'on';
                            app.Image_PHM_result.ImageSource = fullfile('img\', '异常_temp.png');
                        elseif app.status_value_temp == 3 
                            app.Text_PHM1.Value = '正常（重构-低于阈值）';
                            app.Text_PHM2.Value = '无';
                            app.Text_PHM3.Value = '无';
                            app.Text_PHM4.Value = '无';
                            app.Image_branch_size_left.Visible = "off";
                            app.Image_branch_size_right.Visible = "off";
                            app.Image_PHM_result.Visible = 'on';
                            app.Image_PHM_result.ImageSource = fullfile('img\', '运行正常_temp.png');

                        else
                            if app.status_value_temp == 3.3 
                                app.Text_PHM1.Value = '异常（重构-超阈值）';
                                app.Text_PHM2.Value = '1端、2端';
                                app.Text_PHM3.Value = '碰磨';
                                app.Text_PHM4.Value = num2str(app.damage_value_temp);
                            elseif app.status_value_temp == 3.1 
                                app.Text_PHM1.Value = '异常（重构-超阈值）';
                                app.Text_PHM2.Value = '1端';
                                app.Text_PHM3.Value = '碰磨';
                                app.Text_PHM4.Value = num2str(app.damage_value_temp);
                            elseif app.status_value_temp == 3.2 
                                app.Text_PHM1.Value = '异常（重构-超阈值）';
                                app.Text_PHM2.Value = '2端';
                                app.Text_PHM3.Value = '碰磨';
                                app.Text_PHM4.Value = num2str(app.damage_value_temp);
                            end
                                                      
                            if (app.DropDown_branch.Value == "ALL" ) && (app.DropDown_size.Value == "ALL")
                                app.Image_branch_size_left.Visible = "off";
                                app.Image_branch_size_right.Visible = "off";
                                app.Image_PHM_result.Visible = 'on';
                                app.Image_PHM_result.ImageSource = fullfile( 'img\temp\1\', '3尺寸_6分支_网格特征重构图_temp.png');
                            elseif ((app.DropDown_branch.Value == "ALL" ) && (app.DropDown_size.Value ~= "ALL"))||(app.DropDown_branch.Value ~= "ALL" ) && (app.DropDown_size.Value == "ALL")
                                app.Image_branch_size_left.Visible = "off";
                                app.Image_branch_size_right.Visible = "off";
                                app.Image_PHM_result.Visible = 'on';
                                app.Image_PHM_result.ImageSource = fullfile( 'img\', '等待_temp.png');
                            else
                                app.Image_PHM_result.Visible = 'off';
                                app.Image_branch_size_left.Visible = "on";
                                app.Image_branch_size_right.Visible = "on";
                                switch [app.DropDown_branch.Value, app.DropDown_size.Value]
                                    %3*3
                                    case ["X1Y13*3"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X1Y1_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X1Y1_重构_网格特征重构图_temp.png');
                                    case ["X2Y23*3"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X2Y2_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X2Y2_重构_网格特征重构图_temp.png');
                                    case ["X1Y23*3"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X1Y2_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X1Y2_重构_网格特征重构图_temp.png');
                                    case ["X2Y13*3"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X2Y1_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X2Y1_重构_网格特征重构图_temp.png');
                                    case ["X1X23*3"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X1X2_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X1X2_重构_网格特征重构图_temp.png');
                                    case ["Y1Y23*3"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_Y1Y2_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_Y1Y2_重构_网格特征重构图_temp.png');
                                    % 9*9
                                    case ["X1Y19*9"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X1Y1_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X1Y1_重构_网格特征重构图_temp.png');
                                    case ["X2Y29*9"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X2Y2_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X2Y2_重构_网格特征重构图_temp.png');
                                    case ["X1Y29*9"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X1Y2_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X1Y2_重构_网格特征重构图_temp.png');
                                    case ["X2Y19*9"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X2Y1_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X2Y1_重构_网格特征重构图_temp.png');
                                    case ["X1X29*9"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X1X2_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X1X2_重构_网格特征重构图_temp.png');
                                    case ["Y1Y29*9"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_Y1Y2_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_Y1Y2_重构_网格特征重构图_temp.png');
                                    % 27*27
                                    case ["X1Y127*27"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X1Y1_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X1Y1_重构_网格特征重构图_temp.png');
                                    case ["X2Y227*27"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X2Y2_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X2Y2_重构_网格特征重构图_temp.png');
                                    case ["X1Y227*27"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X1Y2_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X1Y2_重构_网格特征重构图_temp.png');
                                    case ["X2Y127*27"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X2Y1_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X2Y1_重构_网格特征重构图_temp.png');
                                    case ["X1X227*27"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X1X2_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X1X2_重构_网格特征重构图_temp.png');
                                    case ["Y1Y227*27"]
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_Y1Y2_原始_网格特征重构图_temp.png');
                                        app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_Y1Y2_重构_网格特征重构图_temp.png');
                                
                                end
                               
                            end

                        end
                     
                    % 进行全域扫描 
                    elseif isequal(value,'On') && isequal(value_Switch_auto,'On')
                        save('data_temp\processed_data_temp.mat', 'data');
                        app.status_damage_value_temp = matpy1();
                        app.status_value_temp = double(app.status_damage_value_temp(1));
                        app.damage_value_temp = double(app.status_damage_value_temp(2));
                        percent = app.slider_Time1.Value;

                        if app.status_value_temp == 0
                            app.auto_normal = [app.auto_normal, percent];
                        elseif app.status_value_temp == 1.3 || app.status_value_temp == 1.1 || app.status_value_temp == 1.2
                            app.auto_abnormal = [app.auto_abnormal, percent];
                        elseif app.status_value_temp == 3
                            app.auto_ae_normal = [app.auto_ae_normal, percent];
                        else 
                            app.auto_ae_abnormal = [app.auto_ae_abnormal, percent];
                        end

                        app.Text_PHM1.Value = 'auto';
                        app.Text_PHM2.Value = 'auto';
                        app.Text_PHM3.Value = 'auto';
                        app.Text_PHM4.Value = 'auto';
                        app.Image_branch_size_left.Visible = "off";
                        app.Image_branch_size_right.Visible = "off";
                        app.Image_PHM_result.ImageSource = fullfile('img\', 'auto_检测数据_temp.png');
                    
                    else
                        app.Text_PHM1.Value = '';
                        app.Text_PHM2.Value = '';
                        app.Text_PHM3.Value = '';
                        app.Text_PHM4.Value = '';
                        app.Image_branch_size_left.Visible = "off";
                        app.Image_branch_size_right.Visible = "off";
                        app.Image_PHM_result.ImageSource = fullfile('img\', 'ai智能_temp.png');
                        
                    
                    end                  
                    
                end
              
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
            
                %坐标轴图更新
                app.axes1.XLim = [-maxLimit maxLimit];
                app.axes1.YLim = [-maxLimit maxLimit];
                app.axes1.XTick = [-maxLimit:maxLimit/5:maxLimit];
                app.axes1.YTick = [-maxLimit:maxLimit/5:maxLimit];
                app.axes1.XGrid = 'on';
                app.axes1.YGrid = 'on';
                %初始化axes2
                app.axes2.XLim = [-maxLimit maxLimit];
                app.axes2.YLim = [-maxLimit maxLimit];
                app.axes2.XTick = [-maxLimit:maxLimit/5:maxLimit];
                app.axes2.YTick = [-maxLimit:maxLimit/5:maxLimit];
                app.axes2.XGrid = 'on';
                app.axes2.YGrid = 'on';
                %初始化axes3
                app.axes3.XLim = [0 zRange];
                app.axes3.YLim = [-maxLimit maxLimit];
                app.axes3.XTick = [0:zRange/4:zRange];
                app.axes3.YTick = [-maxLimit:maxLimit/5:maxLimit];
                app.axes3.XGrid = 'on';
                app.axes3.YGrid = 'on';
                
                rectangle(app.axes1,'Position',[-5,-5,10,10],'Curvature',[1 1],'EdgeColor','r','LineWidth',1,'LineStyle','-');
                rectangle(app.axes1,'Position',[-2.5,-2.5,5,5],'Curvature',[1 1],'EdgeColor','g','LineWidth',1,'LineStyle','-');
                rectangle(app.axes2,'Position',[-5,-5,10,10],'Curvature',[1 1],'EdgeColor','r','LineWidth',1,'LineStyle','-');
                rectangle(app.axes2,'Position',[-2.5,-2.5,5,5],'Curvature',[1 1],'EdgeColor','g','LineWidth',1,'LineStyle','-');
                line(app.axes3,[0,zRange],[-2.5,-2.5],'Color','g','LineWidth',1,'LineStyle','-');
                line(app.axes3,[0,zRange],[2.5,2.5],'Color','g','LineWidth',1,'LineStyle','-');
        end
        
        %功能1：用于信息板的清空、按钮颜色的清空；
        %功能2：用于填写信息板；
        %功能3：实时画curve
        function InfoRefresh(app)     
            states = DataStates(app);
            chnFlag = states{2};
            asyFlag = states{3};
            
            %清空操作
            app.Info1Label.Text = 'Info1';
            app.Info2Label.Text = 'Info2';
            app.Info3Label.Text = 'Info3';
            app.Info4Label.Text = 'Info4';
            app.Info5Label.Text = 'Info5';
                
            app.Info1TextArea.Value = '';
            app.Info2TextArea.Value = '';
            app.Info3TextArea.Value = '';
            app.Info4TextArea.Value = '';
            app.Info5TextArea.Value = '';
                
            cla(app.UIAxes);
            
            %画FFT
            if asyFlag  == 2     
                app.DA.BackgroundColor = [0 1 0];
                pause(0.01)
                FFTCurve(app,chnFlag);
                app.DA.BackgroundColor = [0.94 0.94 0.94];
                %信息板
            end
            
            %画振动-时间曲线
            if asyFlag == 1
                app.DA.BackgroundColor = [0 1 0];
                pause(0.01)
                VibCurve(app,chnFlag);
                app.DA.BackgroundColor = [0.94 0.94 0.94];                
            end
            
            
            %速度曲线
            if asyFlag == 3
                app.DA.BackgroundColor = [0 1 0];
                pause(0.01)
                SpeedCurve(app);
                app.DA.BackgroundColor = [0.94 0.94 0.94];
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DA2_OpeningFcn(app, varargin)
          
            SetData(app)
            data = DrawOrbit2(app);

        end

        % Callback function: Image_branch_size_left, checkbox_Sensor
        function checkbox_Sensor_Callback(app, event)
            tdms3 = app.pushbutton_Open.UserData;
            %2021.4.22 增加
            if isempty(tdms3) == 1 && app.checkbox_Sensor.Value
                msgbox('请先读入TDMS文件！')
                app.checkbox_Sensor.Value = 0;
                return
            end
            
            if app.checkbox_Sensor.Value == 1
                if isempty(app.text_FileName.UserData) == 1
                    tdms3 = tdms3.SensorInfo();  %2021.6.29 修改
                else
                    tdms3 = tdms3.SensorInfo(app.text_FileName.UserData);  %2021.6.29 修改
                end
            else
                tdms3.sensors = [];
            end
            app.pushbutton_Open.UserData = tdms3;
            data = DrawOrbit2(app);
            InfoRefresh(app);
        end

        % Button pushed function: pushbutton_ExportData
        function pushbutton_ExportData_Callback(app, event)
    
            %将当前的显示数据保存到TDMS文件保存目录下，格式为.mat
            tdms3 = app.pushbutton_Open.UserData;
            app.pushbutton_ExportData.BackgroundColor = [0,1,0];
            pause(0.1)
            
            %三种模式
            states = DataStates(app);
            
            if states{1} == 1
                mode = 1;
                tdms3.Slice2Mat(mode,[1:tdms3.fileNum],states{2});
                app.pushbutton_ExportData.BackgroundColor = [0.94,0.94,0.94];
                return;
            end
            
            if states{1} == 2
                mode = 1;
                pos = app.slider_Time1.Value;
                [fileLocation,~] = tdms3.Location(pos);
                tdms3.Slice2Mat(mode,fileLocation,states{2});
                app.pushbutton_ExportData.BackgroundColor = [0.94,0.94,0.94];
                return;
            end
            
            if states{1} == 3
                %2023.9.12修改
                a1 = app.slider_Time1.Value;
                a2 = app.slider_Time2.Value;
                W1 = app.slider_Time1.UserData;
                W2 = app.slider_Time2.UserData;
                
                pos1 = W1 + a1*(W2-W1);
                pos2 = W1 + a2*(W2-W1);
                
                mode = 2;
                if pos1 < pos2
                    tdms3.Slice2Mat(mode,[],states{2},pos1,pos2);
                else
                    tdms3.Slice2Mat(mode,[],states{2},pos2,pos1);
                end
                app.pushbutton_ExportData.BackgroundColor = [0.94,0.94,0.94];
                return
            end
            
        end

        % Button pushed function: pushbutton_Open
        function pushbutton_Open_Callback(app, event)
                      
            app.Image_auto_result.Visible = "off";

            app.pushbutton_Open.BackgroundColor = [0 1 0];
            app.Button.BackgroundColor = [[1 1 1]];
            
          
            if isempty(app.text_FileName.UserData) == 1
                tdms3 = TDMS3();
            else
                filePath = app.text_FileName.UserData;
                tdms3 = TDMS3(filePath,2);
            end
            
            if isempty(tdms3.fileNum) == 0          
                app.text_FileName.UserData = tdms3.filePath;
                app.pushbutton_Open.BackgroundColor = [0 1 1];
                
                %数据状态更新 2022.4.13
                app.Chn.Items = tdms3.channelNames;
                app.Chn.Value = tdms3.channelNames{1,2};
                
                %如果上次打开文件时用到了传感器标定数据，则可以使用上次打开的文件,2022.4.13
                if app.checkbox_Sensor.Value == 1         
                    selection = uiconfirm(app.figure1,'是否使用上次读取的传感器标定结果?','传感器标定结果');
                    if strcmp(selection,'Cancel') == 1
                        if isempty(app.text_FileName.UserData) == 1
                            tdms3 = tdms3.SensorInfo();  %2021.6.29 修改
                        else
                            tdms3 = tdms3.SensorInfo(app.text_FileName.UserData); 
                        end
                    else
                        lastSensor = app.pushbutton_Open.UserData.sensors;
                        tdms3.sensors = lastSensor;
                    end
                end
                
                %读入数据/重新读入数据，重新设置bar1和bar2
                app.slider_Time1.UserData = 0;
                app.slider_Time2.UserData = 1;
                app.slider_Time1.Value = 0;
                app.slider_Time2.Value = 1;
                app.Lamp_Slider1.Color = [0 1 0];
                app.Lamp_Slider2.Color = [1 1 1];
                %微调器
                app.Spinner_1.Value = app.slider_Time1.Value;
                app.Spinner_2.Value = app.slider_Time2.Value;
                %设置未计算最小量的配置
                app.r1.Value = 1;
                app.Button_At.Value = 1;
                
                app.pushbutton_Open.UserData = tdms3;
                data = DrawOrbit2(app);
   
                InfoRefresh(app);
            else
                %2022.4.17增加
                app.pushbutton_Open.BackgroundColor = [0 1 1];
            end
        end

        % Button pushed function: pushbutton_Setting
        function pushbutton_Setting_Callback(app, event)

            app.CApp = TDMSInfo3(app);  %调用子程序
           
            if isempty(app.pushbutton_Open.UserData) == 0
%                 data = DrawOrbit2(app);
%                 InfoRefresh(app);
            end
            
            
        end

        % Button pushed function: pushbutton_WaterFall
        function pushbutton_WaterFall_Callback(app, event)
            %2022.4.20 
            if isempty(app.pushbutton_Open.UserData) == 1
                msgbox("请先读入TDMS数据！");
                return
            end 
            
            states = DataStates(app);
            tdms3 = app.pushbutton_Open.UserData;
  
            %瀑布图的通道顺序
            chnOrder = states{2};
            app.pushbutton_WaterFall.BackgroundColor = [0 1 0];
            
            %画瀑布图, 2022.4.14
            pause(1)            
            tdmsInfo = app.pushbutton_Setting.UserData;
            WFSt = tdmsInfo.WFSt;
            WFEd = tdmsInfo.WFEd;
            WFIt = tdmsInfo.WFIt;
            
             if states{1} == 1
                fileLocation = [1:tdms3.fileNum];
                %瀑布图的文件名
                figureTitle = ['TDMS' tdms3.fileNames{1}(end-9:end-5) '-' tdms3.fileNames{end}(end-9:end-5) '-'];                
             end
             if states{1} == 2
                if app.Lamp_Slider1.Color == [0 1 0]
                    value = app.slider_Time1.Value;
                else
                    value = app.slider_Time2.Value;
                end 
              
%                 value = app.slider_Time1.Value;
                [fileLocation,ptLocation] = tdms3.Location(value);
                figureTitle = ['TDMS' tdms3.fileNames{fileLocation}(end-9:end-5) '-'];                
             end
             if states{1} == 3
                 %msgbox('不支持选定数据生成瀑布图！')
                 w1 = app.slider_Time1.UserData;
                 w2 = app.slider_Time2.UserData;
                 data = tdms3.WLocation(w1,w2,chnOrder)
                 
                 figureTitle = tdms3.channelNames(chnOrder)
                 
                 tdms3.LocalWaterFallPlot(data,1,figureTitle,[WFSt:WFIt:WFEd]);
                 app.pushbutton_WaterFall.BackgroundColor = [0.94 0.94 0.94];
                 msgbox('局部瀑布图绘制完成！')
                 return
             end

            s = tdms3.WaterFallPlot(fileLocation,chnOrder,1,figureTitle,[WFSt:WFIt:WFEd]);        
            app.pushbutton_WaterFall.BackgroundColor = [0.94 0.94 0.94];
            msgbox('瀑布图绘制完成！')
        end

        % Value changed function: slider_Time1
        function slider_Time1_Callback(app, event)
            
            app.Lamp_Slider1.Color = [0 1 0];
            app.Lamp_Slider2.Color = [1 1 1];
            
            if isempty(app.pushbutton_Open.UserData) == 0
                app.plt_Positioning_label(app.UIAxes_2,app.slider_Time1.Value,'g');
                DrawOrbit2(app);       
                states = DataStates(app);
                %只有FFT的时候会更新
                if states{3} == 2
                    InfoRefresh(app);  
                end
            else
                msgbox('请先读入TMDS数据！');
            end         
            app.Spinner_1.Value = app.slider_Time1.Value;           
        end

        % Value changed function: slider_Time2
        function slider_Time2ValueChanged(app, event)
            app.Lamp_Slider2.Color = [0 1 0];
            app.Lamp_Slider1.Color = [1 1 1];
            
            if isempty(app.pushbutton_Open.UserData) == 0
                app.plt_Positioning_label(app.UIAxes_3,app.slider_Time2.Value,'r')
                DrawOrbit2(app);
                states = DataStates(app);
                if states{3} == 2
                    InfoRefresh(app);      
                end
            else
                msgbox('请先读入TMDS数据！');
            end
            
            app.Spinner_2.Value = app.slider_Time2.Value;
        end

        % Selection changed function: DA
        function DASelectionChanged(app, event)
            selectedButton = app.DA.SelectedObject;
            if isempty(app.pushbutton_Open.UserData) == 0
%                 data = DrawOrbit2(app);
                InfoRefresh(app);
            else
                msgbox('请先读入TMDS数据！');
            end
        end

        % Selection changed function: DL
        function DLSelectionChanged(app, event)
            selectedButton = app.DL.SelectedObject;
            
            if isempty(app.pushbutton_Open.UserData) == 0
%                 data = DrawOrbit2(app);
                states = DataStates(app);
                SetGlobalPos(app,states);
                
                InfoRefresh(app);
                app.plt_Positioning_label(app.UIAxes_2,app.slider_Time1.Value,'g');
                app.plt_Positioning_label(app.UIAxes_3,app.slider_Time2.Value,'r');
            else
                msgbox('请先读入TMDS数据！');
            end            
        end

        % Value changed function: Chn
        function ChnValueChanged(app, event)
            value = app.Chn.Value;
            if isempty(app.pushbutton_Open.UserData) == 0
%                 data = DrawOrbit2(app);
                states = DataStates(app);
                if states{3} ~= 3
                    InfoRefresh(app);                                
                end
            else
                msgbox('请先读入TMDS数据！');
            end             
        end

        % Value changed function: Spinner_1
        function Spinner_1ValueChanged(app, event)
            value = app.Spinner_1.Value;
            app.Lamp_Slider1.Color = [0 1 0];
            app.Lamp_Slider2.Color = [1 1 1];
            
            app.slider_Time1.Value = value;
            
            if isempty(app.pushbutton_Open.UserData) == 0
                app.plt_Positioning_label(app.UIAxes_2,app.slider_Time1.Value,'g');
                data = DrawOrbit2(app);
                states = DataStates(app);
                if states{3} == 2
                    InfoRefresh(app);      
                end
            else
                msgbox('请先读入TMDS数据！');
            end             
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            value = app.Spinner_2.Value;            
            app.Lamp_Slider1.Color = [1 1 1];
            app.Lamp_Slider2.Color = [0 1 0];
            
            app.slider_Time2.Value = value;
            
            if isempty(app.pushbutton_Open.UserData) == 0
                app.plt_Positioning_label(app.UIAxes_3,app.slider_Time2.Value,'r');
                data = DrawOrbit2(app);
                states = DataStates(app);
                if states{3} == 2
                    InfoRefresh(app);      
                end
            else
                msgbox('请先读入TMDS数据！');
            end 
            
        end

        % Value changed function: Switch_PHM
        function Switch_PHMValueChanged(app, event)
            value = app.Switch_PHM.Value;            
            if isequal(value,'On')
                app.Lamp_PHM.Color = [0,1,0];
                pause(0.1);
            else
                app.Lamp_PHM.Color = [1,1,1];
                pause(0.1);
            end
        end

        % Value changed function: Switch_PopPic
        function Switch_PopPicValueChanged(app, event)
            value = app.Switch_PopPic.Value;
            if isequal(value,'On')
                app.Lamp_PopPic.Color = [0,1,0];
            else
                app.Lamp_PopPic.Color = [1,1,1];
            end          
        end

        % Callback function
        function Image_branch_size_leftClicked(app, event)

        end

        % Callback function
        function Image_branch_size_rightClicked(app, event)
           
        end

        % Callback function
        function Image_branch_size_rightClicked2(app, event)
            
        end

        % Callback function
        function Image_branch_size_rightClicked3(app, event)
            
        end

        % Image clicked function: Image_branch_size_right
        function Image_branch_size_rightClicked4(app, event)
            
        end

        % Value changed function: DropDown_branch
        function DropDown_branchValueChanged(app, event)
            value = app.Switch_PHM.Value;
            if isequal(value,'On') && (app.status_value_temp == 3.1 || app.status_value_temp == 3.2 || app.status_value_temp == 3.3)
                                             
                % app.Text_PHM1.Value = '异常（重构-超阈值）';
                % app.Text_PHM3.Value = '碰磨';                            
                if (app.DropDown_branch.Value == "ALL" ) && (app.DropDown_size.Value == "ALL")
                    app.Image_branch_size_left.Visible = "off";
                    app.Image_branch_size_right.Visible = "off";
                    app.Image_PHM_result.Visible = 'on';
                    app.Image_PHM_result.ImageSource = fullfile( 'img\temp\1\', '3尺寸_6分支_网格特征重构图_temp.png');
                elseif ((app.DropDown_branch.Value == "ALL" ) && (app.DropDown_size.Value ~= "ALL"))||(app.DropDown_branch.Value ~= "ALL" ) && (app.DropDown_size.Value == "ALL")
                    app.Image_branch_size_left.Visible = "off";
                    app.Image_branch_size_right.Visible = "off";
                    app.Image_PHM_result.Visible = 'on';
                    app.Image_PHM_result.ImageSource = fullfile( 'img\', '等待_temp.png');
                else
                    app.Image_PHM_result.Visible = 'off';
                    app.Image_branch_size_left.Visible = "on";
                    app.Image_branch_size_right.Visible = "on";
                    switch [app.DropDown_branch.Value, app.DropDown_size.Value]
                        %3*3
                        case ["X1Y13*3"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X1Y1_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X1Y1_重构_网格特征重构图_temp.png');
                        case ["X2Y23*3"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X2Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X2Y2_重构_网格特征重构图_temp.png');
                        case ["X1Y23*3"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X1Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X1Y2_重构_网格特征重构图_temp.png');
                        case ["X2Y13*3"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X2Y1_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X2Y1_重构_网格特征重构图_temp.png');
                        case ["X1X23*3"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X1X2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X1X2_重构_网格特征重构图_temp.png');
                        case ["Y1Y23*3"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_Y1Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_Y1Y2_重构_网格特征重构图_temp.png');
                        % 9*9
                        case ["X1Y19*9"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X1Y1_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X1Y1_重构_网格特征重构图_temp.png');
                        case ["X2Y29*9"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X2Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X2Y2_重构_网格特征重构图_temp.png');
                        case ["X1Y29*9"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X1Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X1Y2_重构_网格特征重构图_temp.png');
                        case ["X2Y19*9"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X2Y1_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X2Y1_重构_网格特征重构图_temp.png');
                        case ["X1X29*9"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X1X2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X1X2_重构_网格特征重构图_temp.png');
                        case ["Y1Y29*9"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_Y1Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_Y1Y2_重构_网格特征重构图_temp.png');
                        % 27*27
                        case ["X1Y127*27"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X1Y1_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X1Y1_重构_网格特征重构图_temp.png');
                        case ["X2Y227*27"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X2Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X2Y2_重构_网格特征重构图_temp.png');
                        case ["X1Y227*27"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X1Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X1Y2_重构_网格特征重构图_temp.png');
                        case ["X2Y127*27"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X2Y1_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X2Y1_重构_网格特征重构图_temp.png');
                        case ["X1X227*27"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X1X2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X1X2_重构_网格特征重构图_temp.png');
                        case ["Y1Y227*27"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_Y1Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_Y1Y2_重构_网格特征重构图_temp.png');
                       
                    end

                 end
            else


            end 
        end

        % Value changed function: DropDown_size
        function DropDown_sizeValueChanged(app, event)
            value = app.Switch_PHM.Value;
            if isequal(value,'On') && (app.status_value_temp == 3.1 || app.status_value_temp == 3.2 || app.status_value_temp == 3.3)
                                             
                % app.Text_PHM1.Value = '重构 异常（重构误差超阈值）';
                % app.Text_PHM3.Value = '碰磨';                            
                if (app.DropDown_branch.Value == "ALL" ) && (app.DropDown_size.Value == "ALL")
                    app.Image_branch_size_left.Visible = "off";
                    app.Image_branch_size_right.Visible = "off";
                    app.Image_PHM_result.Visible = 'on';
                    app.Image_PHM_result.ImageSource = fullfile( 'img\temp\1\', '3尺寸_6分支_网格特征重构图_temp.png');
                elseif ((app.DropDown_branch.Value == "ALL" ) && (app.DropDown_size.Value ~= "ALL"))||(app.DropDown_branch.Value ~= "ALL" ) && (app.DropDown_size.Value == "ALL")
                    app.Image_branch_size_left.Visible = "off";
                    app.Image_branch_size_right.Visible = "off";
                    app.Image_PHM_result.Visible = 'on';
                    app.Image_PHM_result.ImageSource = fullfile( 'img\', '等待_temp.png');
                else
                    app.Image_PHM_result.Visible = 'off';
                    app.Image_branch_size_left.Visible = "on";
                    app.Image_branch_size_right.Visible = "on";
                    switch [app.DropDown_branch.Value, app.DropDown_size.Value]
                        %3*3
                        case ["X1Y13*3"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X1Y1_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X1Y1_重构_网格特征重构图_temp.png');
                        case ["X2Y23*3"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X2Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X2Y2_重构_网格特征重构图_temp.png');
                        case ["X1Y23*3"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X1Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X1Y2_重构_网格特征重构图_temp.png');
                        case ["X2Y13*3"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X2Y1_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X2Y1_重构_网格特征重构图_temp.png');
                        case ["X1X23*3"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_X1X2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_X1X2_重构_网格特征重构图_temp.png');
                        case ["Y1Y23*3"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '3_Y1Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '3_Y1Y2_重构_网格特征重构图_temp.png');
                        % 9*9
                        case ["X1Y19*9"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X1Y1_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X1Y1_重构_网格特征重构图_temp.png');
                        case ["X2Y29*9"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X2Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X2Y2_重构_网格特征重构图_temp.png');
                        case ["X1Y29*9"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X1Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X1Y2_重构_网格特征重构图_temp.png');
                        case ["X2Y19*9"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X2Y1_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X2Y1_重构_网格特征重构图_temp.png');
                        case ["X1X29*9"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_X1X2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_X1X2_重构_网格特征重构图_temp.png');
                        case ["Y1Y29*9"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '9_Y1Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '9_Y1Y2_重构_网格特征重构图_temp.png');
                        % 27*27
                        case ["X1Y127*27"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X1Y1_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X1Y1_重构_网格特征重构图_temp.png');
                        case ["X2Y227*27"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X2Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X2Y2_重构_网格特征重构图_temp.png');
                        case ["X1Y227*27"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X1Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X1Y2_重构_网格特征重构图_temp.png');
                        case ["X2Y127*27"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X2Y1_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X2Y1_重构_网格特征重构图_temp.png');
                        case ["X1X227*27"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_X1X2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_right.ImageSource = fullfile( 'img\temp\1\', '27_X1X2_重构_网格特征重构图_temp.png');
                        case ["Y1Y227*27"]
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_Y1Y2_原始_网格特征重构图_temp.png');
                            app.Image_branch_size_left.ImageSource = fullfile( 'img\temp\1\', '27_Y1Y2_重构_网格特征重构图_temp.png');
                       
                    end

                 end
            else


            end 
            
        end

        % Value changed function: Switch_auto
        function Switch_autoValueChanged(app, event)
            value = app.Switch_auto.Value;
            
            if isequal(value,'On') && isequal(app.Switch_PHM.Value,'On')
                quest_temp = questdlg('是否进行全域扫描','全域扫描','Yes','No','No');
            
                if isequal(value,'On') && isequal(quest_temp,'Yes')              
                    app.Lamp_auto.Color = [0,1,0];
                    app.Lamp_Slider1.Color = [0 1 0];
                    app.Lamp_Slider2.Color = [1 1 1];
                    
                    if isempty(app.pushbutton_Open.UserData) == 0
        
        
                        h = waitbar(0.01,'正在全域扫描诊断','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
                        setappdata(h,'canceling',0);
                        
                        app.slider_Time1.Value = 0;
                        app.slider_Time2.Value = 1;
                        cla(app.UIAxes_2);
                        cla(app.UIAxes_3);
    
                        % 初始化情况，避免上一次全域扫描结果的影响
                        app.auto_normal = []; 
                        app.auto_abnormal = [];
                        app.auto_ae_normal = []; 
                        app.auto_ae_abnormal = [];
                                       
                        for i = 1:100
                          
                            %进度条 添加 2023.11.30
                            str = ['扫描中...',num2str(i),'%'];
                            waitbar(i/100,h,str);
        
                            app.plt_Positioning_label(app.UIAxes_2,i/100,'y');
        
                            if getappdata(h,'canceling') %如果在中途取消了扫描
                                app.Lamp_auto.Color = [1,1,1];    
                                app.Switch_auto.Value = 'Off';                            
                                delete(h);
                                app.slider_Time1.Value = 0;
                                app.slider_Time2.Value = 1;
                                cla(app.UIAxes_2);
                                cla(app.UIAxes_3);   
                                app.Image_PHM_result.ImageSource = fullfile('img\', 'ai智能_temp.png');
                                
                                msgbox('全域扫描已取消！','注意：','warn');
        
                                
                                return
                            end
        
                            app.slider_Time1.Value = i/100;
                            
                            app.Spinner_1.Value = app.slider_Time1.Value;
                           
                            value_temp = app.Switch_auto.Value;                   
                            if isequal(value_temp,'Off')
                                app.Image_auto_result.Visible = "off";
                                app.Lamp_auto.Color = [1,1,1]; 
                            end
        
                            DrawOrbit2(app);
                            
                            if i == 100
                                auto_normal_temp = app.auto_normal;
                                auto_abnormal_temp = app.auto_abnormal;
                                auto_ae_normal_temp = app.auto_ae_normal;
                                auto_ae_abnormal_temp = app.auto_ae_abnormal;


                                save('data_temp\auto_result', 'auto_normal_temp', 'auto_abnormal_temp', 'auto_ae_normal_temp', 'auto_ae_abnormal_temp');
                                % 调用Python画图
                                matpy2();
                                
                                app.Image_auto_result.ImageSource = fullfile( 'img\temp\2\', 'auto_result_temp.png');
                                app.Image_auto_result.Visible = "on";
        
                            end
        
        
                        end
        
                        delete(h);
                        msgbox('全域扫描诊断完成');
                        app.slider_Time1.Value = 0;
                        app.slider_Time2.Value = 1;
                        cla(app.UIAxes_2);
                        cla(app.UIAxes_3);
                        app.Lamp_auto.Color = [1,1,1]; 
                        app.Image_PHM_result.ImageSource = fullfile('img\', 'ai智能_temp.png');
                        app.Switch_auto.Value = 'Off';
        
                    else
                        msgbox('请先读入TMDS数据！');
                        app.Image_auto_result.Visible = "off";
                        app.Lamp_auto.Color = [1,1,1];    
                        app.Switch_auto.Value = 'Off';
                    end 
                else
                    app.Lamp_auto.Color = [1,1,1];    
                    app.Switch_auto.Value = 'Off';
                end
            else
                pause(0.1);
                app.Switch_auto.Value = 'Off';
            
            end


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Image_auto_result.Visible = "off";

            % 快速检索功能 2023.11.23
            app.pushbutton_Open.BackgroundColor = [0 1 0];
            app.Button.BackgroundColor = [0 1 0];

            if ~strcmp(app.EditField_2.Value(end),'\')
                app.EditField_2.Value = [app.EditField_2.Value, '\'];
            end
            
            tdms3 = TDMS3(app.TDMSRealTimeData_EditField.Value,app.EditField.Value,app.EditField_2.Value);

            all_points = 0;
            for i_filePoints=1:tdms3.fileNum
                all_points = all_points +tdms3.filePoints(i_filePoints);
            end
            app.EditField_3.Value = all_points;
           
            if isempty(tdms3.fileNum) == 0          
                app.text_FileName.UserData = tdms3.filePath;
                app.pushbutton_Open.BackgroundColor = [0 1 1];
                app.Button.BackgroundColor = [0 1 0];
                
                %数据状态更新 2022.4.13
                app.Chn.Items = tdms3.channelNames;
                app.Chn.Value = tdms3.channelNames{1,2};
                
                %如果上次打开文件时用到了传感器标定数据，则可以使用上次打开的文件,2022.4.13
                if app.checkbox_Sensor.Value == 1         
                    selection = uiconfirm(app.figure1,'是否使用上次读取的传感器标定结果?','传感器标定结果');
                    if strcmp(selection,'Cancel') == 1
                        if isempty(app.text_FileName.UserData) == 1
                            tdms3 = tdms3.SensorInfo();  %2021.6.29 修改
                        else
                            tdms3 = tdms3.SensorInfo(app.text_FileName.UserData); 
                        end
                    else
                        lastSensor = app.pushbutton_Open.UserData.sensors;
                        tdms3.sensors = lastSensor;
                    end
                end
                
                %读入数据/重新读入数据，重新设置bar1和bar2
                app.slider_Time1.UserData = 0;
                app.slider_Time2.UserData = 1;
                app.slider_Time1.Value = 0;
                app.slider_Time2.Value = 1;
                app.Lamp_Slider1.Color = [0 1 0];
                app.Lamp_Slider2.Color = [1 1 1];
                %微调器
                app.Spinner_1.Value = app.slider_Time1.Value;
                app.Spinner_2.Value = app.slider_Time2.Value;
                %设置未计算最小量的配置
                app.r1.Value = 1;
                app.Button_At.Value = 1;
                
                app.pushbutton_Open.UserData = tdms3;
                data = DrawOrbit2(app);
   
                InfoRefresh(app);
            else
                %2022.4.17增加
                app.pushbutton_Open.BackgroundColor = [0 1 1];
            end
            
            if app.EditField_3.Value == 0
                app.slider_Time1.Value = 0;
            elseif app.pointEditField.Value/app.EditField_3.Value > 1
                app.slider_Time1.Value = 1;
            else
                app.slider_Time1.Value = app.pointEditField.Value/app.EditField_3.Value;
            end
            app.Lamp_Slider1.Color = [0 1 0];
            app.Lamp_Slider2.Color = [1 1 1];
            
            if isempty(app.pushbutton_Open.UserData) == 0
                DrawOrbit2(app);       
                states = DataStates(app);
                %只有FFT的时候会更新
                if states{3} == 2
                    InfoRefresh(app);  
                end
            else
                msgbox('请先读入TMDS数据！');
            end         
            app.Spinner_1.Value = app.slider_Time1.Value;  

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DA2';
            app.figure1.Tag = 'FigF';
            app.UIAxes = uiaxes(app.figure1);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_3 = uiaxes(app.figure1);
            title(app.UIAxes_3, '.')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_3.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_3.ZTick = [0 0.5 1];
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            app.UIAxes_2 = uiaxes(app.figure1);
            title(app.UIAxes_2, '.')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.ZTick = [0 0.5 1];
            app.text_Setting = uilabel(app.figure1);
            app.text_Setting.Tag = 'text_Setting';
            app.text_Setting.Text = 'AMB数据分析平台';
            app.edit_TimeV2 = uieditfield(app.figure1, 'text');
            app.edit_TimeV2.Tag = 'edit_Frq';
            app.edit_TimeV1 = uieditfield(app.figure1, 'text');
            app.edit_TimeV1.Tag = 'edit_Time';
            app.pushbutton_Open = uibutton(app.figure1, 'push');
            app.pushbutton_Open.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Open_Callback, true);
            app.pushbutton_Open.Tag = 'pushbutton_Open';
            app.pushbutton_Open.Text = '打开文件';
            app.text_FileName = uilabel(app.figure1);
            app.text_FileName.Tag = 'text_FileName';
            app.text_FileName.Text = '';
            app.pushbutton_Setting = uibutton(app.figure1, 'push');
            app.pushbutton_Setting.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Setting_Callback, true);
            app.pushbutton_Setting.Tag = 'pushbutton_Setting';
            app.pushbutton_Setting.Text = '设置';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = '数据分析';
            app.uipanel1.Tag = 'uipanel1';
            app.pushbutton_WaterFall = uibutton(app.uipanel1, 'push');
            app.pushbutton_WaterFall.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_WaterFall_Callback, true);
            app.pushbutton_WaterFall.Tag = 'pushbutton_WaterFall';
            app.pushbutton_WaterFall.Text = '瀑布图';
            app.DA = uibuttongroup(app.uipanel1);
            app.DA.SelectionChangedFcn = createCallbackFcn(app, @DASelectionChanged, true);
            app.Button_FFT = uiradiobutton(app.DA);
            app.Button_FFT.Text = 'FFT';
            app.Button_At = uiradiobutton(app.DA);
            app.Button_At.Text = '振动-时间';
            app.Button_At.Value = true;
            app.Button_Vt = uiradiobutton(app.DA);
            app.Button_Vt.Text = '转速-时间';
            app.Label_2 = uilabel(app.DA);
            app.Label_2.Text = '弹图';
            app.Switch_PopPic = uiswitch(app.DA, 'slider');
            app.Switch_PopPic.ValueChangedFcn = createCallbackFcn(app, @Switch_PopPicValueChanged, true);
            app.Lamp_PopPic = uilamp(app.DA);
            app.text_X1Y1 = uilabel(app.figure1);
            app.text_X1Y1.Tag = 'text_X1Y1';
            app.text_X1Y1.Text = '';
            app.text_X2Y2 = uilabel(app.figure1);
            app.text_X2Y2.Tag = 'text_X2Y2';
            app.text_X2Y2.Text = '';
            app.text_Z = uilabel(app.figure1);
            app.text_Z.Tag = 'text_Z';
            app.text_Z.Text = '';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = '显示设置';
            app.uipanel4.Tag = 'uipanel4';
            app.checkbox_Sensor = uicheckbox(app.uipanel4);
            app.checkbox_Sensor.ValueChangedFcn = createCallbackFcn(app, @checkbox_Sensor_Callback, true);
            app.checkbox_Sensor.Tag = 'checkbox_Sensor';
            app.checkbox_Sensor.Text = '传感器标定';
            app.Label = uilabel(app.uipanel4);
            app.Label.Text = '转速传感器';
            app.Lamp = uilamp(app.uipanel4);
            app.Panel = uipanel(app.figure1);
            app.Panel.Title = '数据选择';
            app.DL = uibuttongroup(app.Panel);
            app.DL.SelectionChangedFcn = createCallbackFcn(app, @DLSelectionChanged, true);
            app.r1 = uiradiobutton(app.DL);
            app.r1.Tag = 'DataSelect_All';
            app.r1.Text = '全部数据';
            app.r1.Value = true;
            app.r2 = uiradiobutton(app.DL);
            app.r2.Tag = 'DataSelect_Single';
            app.r2.Text = '当前TDMS';
            app.r3 = uiradiobutton(app.DL);
            app.r3.Tag = 'DataSelect_Fix';
            app.r3.Text = '指定数据';
            app.dataTime = uieditfield(app.DL, 'numeric');
            app.sLabel = uilabel(app.DL);
            app.sLabel.Text = 's';
            app.Panel_2 = uipanel(app.figure1);
            app.Panel_2.Title = '通道选择';
            app.Chn = uilistbox(app.Panel_2);
            app.Chn.Items = {};
            app.Chn.Multiselect = 'on';
            app.Chn.ValueChangedFcn = createCallbackFcn(app, @ChnValueChanged, true);
            app.Chn.Tag = 'ListBox_Chn';
            app.Chn.Value = {};
            app.Panel_Info = uipanel(app.figure1);
            app.Panel_Info.Title = '信息';
            app.Info1TextArea = uitextarea(app.Panel_Info);
            app.Info2TextArea = uitextarea(app.Panel_Info);
            app.Info3TextArea = uitextarea(app.Panel_Info);
            app.Info4TextArea = uitextarea(app.Panel_Info);
            app.Info5TextArea = uitextarea(app.Panel_Info);
            app.Info1Label = uilabel(app.Panel_Info);
            app.Info1Label.Text = 'Info1';
            app.Info2Label = uilabel(app.Panel_Info);
            app.Info2Label.Text = 'Info2';
            app.Info3Label = uilabel(app.Panel_Info);
            app.Info3Label.Text = 'Info3';
            app.Info4Label = uilabel(app.Panel_Info);
            app.Info4Label.Text = 'Info4';
            app.Info5Label = uilabel(app.Panel_Info);
            app.Info5Label.Text = 'Info5';
            app.slider_Time2 = uislider(app.figure1);
            app.slider_Time2.Limits = [0 1];
            app.slider_Time2.MajorTicks = [];
            app.slider_Time2.ValueChangedFcn = createCallbackFcn(app, @slider_Time2ValueChanged, true);
            app.slider_Time2.MinorTicks = [];
            app.slider_Time2.Tag = 'slider_Time';
            app.slider_Time2.Value = 1;
            app.slider_Time1 = uislider(app.figure1);
            app.slider_Time1.Limits = [0 1];
            app.slider_Time1.MajorTicks = [];
            app.slider_Time1.ValueChangedFcn = createCallbackFcn(app, @slider_Time1_Callback, true);
            app.slider_Time1.MinorTicks = [];
            app.slider_Time1.Tag = 'slider_Time';
            app.Lamp_Slider1 = uilamp(app.figure1);
            app.Lamp_Slider2 = uilamp(app.figure1);
            app.text8_2 = uilabel(app.figure1);
            app.text8_2.Tag = 'text8';
            app.text8_2.Text = '当前时间/总时间s --- 转速(Hz/RPM)';
            app.Spinner_1 = uispinner(app.figure1);
            app.Spinner_1.Step = 0.001;
            app.Spinner_1.Limits = [0 1];
            app.Spinner_1.ValueChangedFcn = createCallbackFcn(app, @Spinner_1ValueChanged, true);
            app.Spinner_2 = uispinner(app.figure1);
            app.Spinner_2.Step = 0.001;
            app.Spinner_2.Limits = [0 1];
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Value = 1;
            app.pushbutton_ExportData = uibutton(app.figure1, 'push');
            app.pushbutton_ExportData.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_ExportData_Callback, true);
            app.pushbutton_ExportData.Tag = 'pushbutton_ExportData';
            app.pushbutton_ExportData.Text = '导出数据';
            app.PHMLabel = uilabel(app.figure1);
            app.PHMLabel.Text = 'PHM';
            app.Switch_PHM = uiswitch(app.figure1, 'slider');
            app.Switch_PHM.ValueChangedFcn = createCallbackFcn(app, @Switch_PHMValueChanged, true);
            app.Panel_PHM = uipanel(app.figure1);
            app.Panel_PHM.Title = '系统健康状态';
            app.Label_3 = uilabel(app.Panel_PHM);
            app.Label_3.Text = '运行状态';
            app.Text_PHM1 = uitextarea(app.Panel_PHM);
            app.Label_4 = uilabel(app.Panel_PHM);
            app.Label_4.Text = '故障类型';
            app.Text_PHM3 = uitextarea(app.Panel_PHM);
            app.Image_PHM_result = uiimage(app.Panel_PHM);
            app.Image_PHM_result.ImageSource = fullfile(pathToMLAPP, 'img', 'ai智能_temp.png');
            app.Label_5 = uilabel(app.Panel_PHM);
            app.Label_5.Text = '分支';
            app.DropDown_branch = uidropdown(app.Panel_PHM);
            app.DropDown_branch.Items = {'ALL', 'X1Y1', 'X2Y2', 'X1Y2', 'X2Y1', 'X1X2', 'Y1Y2'};
            app.DropDown_branch.ValueChangedFcn = createCallbackFcn(app, @DropDown_branchValueChanged, true);
            app.DropDown_branch.Value = 'X1Y2';
            app.Label_6 = uilabel(app.Panel_PHM);
            app.Label_6.Text = '尺寸';
            app.DropDown_size = uidropdown(app.Panel_PHM);
            app.DropDown_size.Items = {'ALL', '3*3', '9*9', '27*27'};
            app.DropDown_size.ValueChangedFcn = createCallbackFcn(app, @DropDown_sizeValueChanged, true);
            app.DropDown_size.Value = '3*3';
            app.Image_branch_size_left = uiimage(app.Panel_PHM);
            app.Image_branch_size_left.ImageClickedFcn = createCallbackFcn(app, @checkbox_Sensor_Callback, true);
            app.Image_branch_size_left.ImageSource = fullfile(pathToMLAPP, 'img', '运行正常_temp 拷贝.png');
            app.Image_branch_size_right = uiimage(app.Panel_PHM);
            app.Image_branch_size_right.ImageClickedFcn = createCallbackFcn(app, @Image_branch_size_rightClicked4, true);
            app.Image_branch_size_right.ImageSource = fullfile(pathToMLAPP, 'img', '运行正常_temp 拷贝.png');
            app.Label_8 = uilabel(app.Panel_PHM);
            app.Label_8.Text = '严重程度';
            app.Text_PHM4 = uitextarea(app.Panel_PHM);
            app.Label_10 = uilabel(app.Panel_PHM);
            app.Label_10.Text = '故障定位';
            app.Text_PHM2 = uitextarea(app.Panel_PHM);
            app.Lamp_PHM = uilamp(app.figure1);
            app.Lamp_auto = uilamp(app.figure1);
            app.PHMLabel_2 = uilabel(app.figure1);
            app.PHMLabel_2.Text = 'AUTO';
            app.Switch_auto = uiswitch(app.figure1, 'slider');
            app.Switch_auto.ValueChangedFcn = createCallbackFcn(app, @Switch_autoValueChanged, true);
            app.Image_auto_result = uiimage(app.figure1);
            app.Image_auto_result.ImageSource = fullfile(pathToMLAPP, 'img', '运行正常_temp 拷贝.png');
            app.TDMSRealTimeData_EditFieldLabel = uilabel(app.figure1);
            app.TDMSRealTimeData_EditFieldLabel.Text = 'TDMS：RealTimeData_';
            app.TDMSRealTimeData_EditField = uieditfield(app.figure1, 'numeric');
            app.TDMSRealTimeData_EditField.ValueDisplayFormat = '%.0f';
            app.pointEditFieldLabel = uilabel(app.figure1);
            app.pointEditFieldLabel.Text = '| point';
            app.pointEditField = uieditfield(app.figure1, 'numeric');
            app.pointEditField.ValueDisplayFormat = '%.0f';
            app.Button = uibutton(app.figure1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始检索';
            app.Label_7 = uilabel(app.figure1);
            app.Label_7.Text = '快速检索 路径：';
            app.EditField_2 = uieditfield(app.figure1, 'text');
            app.EditField_2.Value = 'D:\DTOOL\课题-升降速异常检测\测试\测试数据';
            app.EditField_3Label_2 = uilabel(app.figure1);
            app.EditField_3Label_2.Text = '/';
            app.EditField_3 = uieditfield(app.figure1, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%.0f';
            app.Label_9 = uilabel(app.figure1);
            app.Label_9.Text = '~';
            app.EditField = uieditfield(app.figure1, 'numeric');
            app.EditField.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AMB_FSL(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)DA2_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end