classdef AMB_FSL_3_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AMB_FSLUIFigure                matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        Panel_3                        matlab.ui.container.Panel
        text3                          matlab.ui.control.Label
        save_resul_path                matlab.ui.control.EditField
        pushbutton_load_dir_2          matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        Panel_9                        matlab.ui.container.Panel
        Panel_14                       matlab.ui.container.Panel
        Chn_par                        matlab.ui.control.ListBox
        Panel_13                       matlab.ui.container.Panel
        pushbutton_delete_filename     matlab.ui.control.Button
        Chn_load_file_list             matlab.ui.control.ListBox
        Panel_8                        matlab.ui.container.Panel
        Panel_12                       matlab.ui.container.Panel
        pushbutton_suspected_result    matlab.ui.control.Button
        CheckBox_suspected_result      matlab.ui.control.CheckBox
        Chn_suspected_result           matlab.ui.control.ListBox
        Panel_11                       matlab.ui.container.Panel
        pushbutton_serious_result      matlab.ui.control.Button
        CheckBox_serious_result        matlab.ui.control.CheckBox
        Chn_serious_result             matlab.ui.control.ListBox
        Panel_10                       matlab.ui.container.Panel
        text_Scan_Progress             matlab.ui.control.Label
        pushbutton_total_result        matlab.ui.control.Button
        CheckBox_total_result          matlab.ui.control.CheckBox
        Chn_total_result               matlab.ui.control.ListBox
        Chn_num_normal_serious_suspected  matlab.ui.control.ListBox
        Panel                          matlab.ui.container.Panel
        Panel_7                        matlab.ui.container.Panel
        pushbutton_load_file           matlab.ui.control.Button
        pushbutton_load_dir            matlab.ui.control.Button
        Panel_6                        matlab.ui.container.Panel
        ButtonGroup_scn_mode           matlab.ui.container.ButtonGroup
        Button_scn_mode_3              matlab.ui.control.RadioButton
        Button_scn_mode_2              matlab.ui.control.RadioButton
        Button_scn_mode                matlab.ui.control.RadioButton
        Lamp_Start_Scan                matlab.ui.control.Lamp
        Switch_Start_Scan              matlab.ui.control.Switch
        Panel_5                        matlab.ui.container.Panel
        ButtonGroup_save_set           matlab.ui.container.ButtonGroup
        Button__save_set_2             matlab.ui.control.RadioButton
        Button__save_set               matlab.ui.control.RadioButton
        Panel_4                        matlab.ui.container.Panel
        pushbutton_Setting             matlab.ui.control.Button
        text_Setting                   matlab.ui.control.Label
        TXTTab                         matlab.ui.container.Tab
        Panel_16                       matlab.ui.container.Panel
        txtPanel                       matlab.ui.container.Panel
        pushbutton_delete_filename_3   matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        pushbutton_delete_filename_2   matlab.ui.control.Button
        Panel_18                       matlab.ui.container.Panel
        Chn_load_file_list_2           matlab.ui.control.ListBox
        Panel_15                       matlab.ui.container.Panel
        Panel_17                       matlab.ui.container.Panel
        rotor_angle_radLabel           matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        speed_hzEditField              matlab.ui.control.EditField
        speed_hzEditFieldLabel         matlab.ui.control.Label
        EditField_2                    matlab.ui.control.EditField
        EditField_2Label               matlab.ui.control.Label
        rotor_angle_radEditField       matlab.ui.control.EditField
        rotor_angle_radEditFieldLabel  matlab.ui.control.Label
        tt_100uEditField               matlab.ui.control.EditField
        tt_100uEditFieldLabel          matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        EditFieldLabel                 matlab.ui.control.Label
        tt_secEditField                matlab.ui.control.EditField
        tt_secEditFieldLabel           matlab.ui.control.Label
        text_Scan_Progress_2           matlab.ui.control.Label
    end


%%%%%%%%%%%%%添加自己需要用到的属性变量（用于全局传递）%%%%%%%%%%%%%%
        
    properties (Access = public)
        CApp % Description


        select_fileName; % 被选着的具体文件名
        select_filepath; % 被选择的具体文件夹路径
        
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%添加自己用到的函数%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    methods (Access = public)

        function ReadCsvData(app,filepath,filename)

        end

        %设置setting界面数目
        function SetData(app)
            %用.\config\SetData.csv中的数据进行初始化赋值
            csvFilePath = fullfile('.\config\', 'SetData_temp.csv');
            
            % 读取CSV文件
            csvData = importdata(csvFilePath);
            
            % 获取对应列的索引
            scn_channel_idx = find(strcmp(csvData.textdata, 'scn_channel'));
            scn_channel_max_idx = find(strcmp(csvData.textdata, 'scn_channel_max'));
            scn_suspected_threshold_idx = find(strcmp(csvData.textdata, 'scn_suspected_threshold'));
            scn_serious_threshold_idx = find(strcmp(csvData.textdata, 'scn_serious_threshold'));
            set_scanning_sensitivity_idx = find(strcmp(csvData.textdata, 'set_scanning_sensitivity'));
            set_scanning_density_idx = find(strcmp(csvData.textdata, 'set_scanning_density'));       

            
            % 获取对应列的数据（排除 NaN）
            scn_channel_data = csvData.data(scn_channel_idx, :);
            scn_channel_max_data = csvData.data(scn_channel_max_idx, :);
            scn_suspected_threshold_data = csvData.data(scn_suspected_threshold_idx, :);
            scn_serious_threshold_data = csvData.data(scn_serious_threshold_idx, :);
            set_scanning_sensitivity_data = csvData.data(set_scanning_sensitivity_idx, :);
            set_scanning_density_data = csvData.data(set_scanning_density_idx, :);
            
            scn_channel_data = scn_channel_data(~isnan(scn_channel_data));
            scn_channel_max_data = scn_channel_max_data(~isnan(scn_channel_max_data));
            scn_suspected_threshold_data = scn_suspected_threshold_data(~isnan(scn_suspected_threshold_data));
            scn_serious_threshold_data = scn_serious_threshold_data(~isnan(scn_serious_threshold_data));
            set_scanning_sensitivity_data = set_scanning_sensitivity_data(~isnan(set_scanning_sensitivity_data));
            set_scanning_density_data = set_scanning_density_data(~isnan(set_scanning_density_data));            
            
            % 将数值数据转换为字符串
            scn_channel_str = sprintf('%g,', scn_channel_data);
            scn_channel_max_str = sprintf('%g,', scn_channel_max_data);
            scn_suspected_threshold_str = sprintf('%g,', scn_suspected_threshold_data);
            scn_serious_threshold_str = sprintf('%g,', scn_serious_threshold_data);
            set_scanning_sensitivity_str = sprintf('%g,', set_scanning_sensitivity_data);
            set_scanning_density_str = sprintf('%g,', set_scanning_density_data);

            scn_channel = scn_channel_str(1:end-1);  % 移除末尾的逗号
            scn_channel_max = scn_channel_max_str(1:end-1);  % 移除末尾的逗号
            scn_suspected_threshold = scn_suspected_threshold_str(1:end-1);  % 移除末尾的逗号
            scn_serious_threshold = scn_serious_threshold_str(1:end-1);  % 移除末尾的逗号
            set_scanning_sensitivity = set_scanning_sensitivity_str(1:end-1);  % 移除末尾的逗号
            set_scanning_density = set_scanning_density_str(1:end-1);  % 移除末尾的逗号
                    
            SetDataInfo = struct('scn_channel',scn_channel,'scn_channel_max',scn_channel_max,...
                'scn_suspected_threshold',scn_suspected_threshold,'scn_serious_threshold',scn_serious_threshold,...
                'set_scanning_sensitivity',set_scanning_sensitivity,...                
                'set_scanning_density',set_scanning_density);
            
            app.pushbutton_Setting.UserData = SetDataInfo;
         
        end
        
        % 用于更新显示框
        function update_scan_result_display(app,clear_1,num_normal_serious_suspected,total_result,serious_result,suspected_result)
        
            if clear_1 ~= 1 % 清空
                    % 扫描进度
                app.text_Scan_Progress.Text = '扫描进度：';
                    % 数值部分
                app.Chn_num_normal_serious_suspected.Items = {'正常文件：','疑似故障：','严重故障：'};
                app.Chn_num_normal_serious_suspected.Value = {};
                    % 列表部分
                app.Chn_total_result.Items = {};
                app.Chn_total_result.Value = {};   
    
                % 更新"严重故障-文件列表"
                app.Chn_serious_result.Items = {};
                app.Chn_serious_result.Value = {};   
    
                % 更新"疑似故障-文件列表"
                app.Chn_suspected_result.Items = {};
                app.Chn_suspected_result.Value = {};             
                            
            else
                % 更新"整体扫描结果"
                    % 数值部分
                if ~isempty(num_normal_serious_suspected{1}) % 如果装了内容
                    app.Chn_num_normal_serious_suspected.Items = num_normal_serious_suspected;
                    app.Chn_num_normal_serious_suspected.Value = {};
                end
                    % 列表部分
                if ~isempty(total_result{1}) % 如果装了内容
                    app.Chn_total_result.Items = total_result;
                    app.Chn_total_result.Value = {}; 
                end
    
                % 更新"严重故障-文件列表"
                if ~isempty(serious_result{1})
                    app.Chn_serious_result.Items = serious_result;
                    app.Chn_serious_result.Value = {};
                end
    
                % 更新"疑似故障-文件列表"
                if ~isempty(suspected_result{1})
                    app.Chn_suspected_result.Items = suspected_result;
                    app.Chn_suspected_result.Value = {};
                end
                
            end

        end


        % 用于扫描结果的保存功能
        function save_file_scan_result(app, save_file_name, File_scan_results)
            try
                % 先检测要保存的内容是否为空
                if isempty(File_scan_results)
                    return
                end
                % 获取当前时间戳
                time_stamp = datestr(now, 'yyyymmddHHMMSS');
                % 生成文件名
                file_name = [save_file_name, time_stamp, '.txt'];
                % 生成文件路径
                if app.save_resul_path.Value(end) == '\'
                    file_path = fullfile(app.save_resul_path.Value, file_name);
                else
                    file_path = fullfile(app.save_resul_path.Value, '\', file_name);
                end
        
                % 保存文件内容
                fid = fopen(file_path, 'w');
                for i = 1:length(File_scan_results)
                    fprintf(fid, '%s\n', File_scan_results{i});
                end
                fclose(fid);
        
                msgbox('扫描结果保存成功');
            catch
                msgbox('扫描结果保存失败');
            end
            
        end

        % 2024.1.18 用于更新参数显示框
        function update_par_dis(app)
%             fieldNamesCell = fieldnames(app.pushbutton_Setting.UserData);

            fieldNamesCell = {  
           '待检通道';
           '保护间隙';
           '故障下界';
           '故障上界';
           '敏感阈度';
           '扫描密度'            
            }

            structValuesCell = struct2cell(app.pushbutton_Setting.UserData);

            % 初始化一个新的cell数组
            concatenatedCell = cell(size(fieldNamesCell, 1), 1);
            % 遍历每一行并进行拼接
            for row = 1:size(fieldNamesCell, 1)
                % 获取当前行的field name和对应的struct value
                fieldName = fieldNamesCell{row};
                structValue = structValuesCell{row};
                % 使用'_:_'连接并存储到新的cell数组
                concatenatedCell{row} = [fieldName, ' : ', structValue];
            end

            app.Chn_par.Items = concatenatedCell;
            app.Chn_par.Value = {};

        end
        
        % 检测输入的路径是否存在
        function exit_temp = CheckPath(app,filePath,type) 
            
            % 使用exist函数检测文件是否存在
            exit_temp =  exist(filePath, type)
            if exit_temp == 2 % 文件存在
                
            elseif exit_temp == 7 %路径存在
                
            else
                msgbox('保存路径错误！','错误','error')
                app.pushbutton_load_dir.BackgroundColor = [1 1 1];
            end

        end

        function cle_txt_cal(app)
            app.UITable.ColumnName = {'Label'; '名称'; '单位'; '倍数';
                '数据点 1'; '数据点 2'; '数据点 3'; '数据点 4'; '数据点 5'; '数据点 6'; '数据点 7'; '数据点 8'; '数据点 9'; '数据点 10'; '数据点 11'; '数据点 12'; '数据点 13'; '数据点 14'; '数据点 15'; '数据点 16'; '数据点 17'; '数据点 18'; '数据点 19'; '数据点 20'; '数据点 21'; '数据点 22'; '数据点 23'; '数据点 24'; '数据点 25'; '数据点 26'; '数据点 27'; '数据点 28'; '数据点 29'; '数据点 30'; '数据点 31'; '数据点 32'; '数据点 33'; '数据点 34'; '数据点 35'; '数据点 36'; '数据点 37'; '数据点 38'; '数据点 39'; '数据点 40'; '数据点 41'; '数据点 42'; '数据点 43'; '数据点 44'; '数据点 45'; '数据点 46'; '数据点 47'; '数据点 48'; '数据点 49'};

            app.tt_secEditField.Value = '';
            app.tt_100uEditField.Value =  '';
            app.EditField.Value = '';

            app.UITable.Data = [];

            app.speed_hzEditField.Value = '';
            app.rotor_angle_radEditField.Value = '';
            app.EditField_2.Value = '';
            app.Chn_load_file_list_2.Items = {};
        end


    end


    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            SetData(app);
            update_par_dis(app); % 初始化参数显示

        end

        % Button pushed function: pushbutton_load_file
        function pushbutton_load_fileButtonPushed(app, event)
            app.pushbutton_load_file.BackgroundColor = [0 1 0];

            % 这是文件选择框打开的位置
            if isempty(app.select_filepath)==1
                uigetfile_path_temp = 'C:\';
            else
                uigetfile_path_temp = app.select_filepath;
            end

            % 读取批次文件
            [fileName,filePath] = uigetfile({'*.txt;*.tdms'},'选择文件',uigetfile_path_temp,'MultiSelect','on');

%             if strcmp(class(fileName), 'double')||strcmp(class(filePath), 'double')
            if isa(fileName, 'double')||isa(filePath, 'double')
                msgbox('文件载入有误！','错误','error')
                app.pushbutton_load_file.BackgroundColor = [1 1 1];
                return
            else
                % uigetfile 函数在选择一个文件时返回一个字符串，而在选择多个文件时返回一个单元格数组,为避免后续比对时索引出错，这里要先判断是单个还是多个文件
                % 要用isa 之间判断，因为如果用class，它返回的是 'cell' 字符串，而不是 cell 类型。因此，需要使用字符串进行比较麻烦
                if isa(fileName, 'char')
                    fileName = cellstr(fileName);%将字符串数组（在matlab中字符串是单个字符构成的 数组 ）也变为 1个 的cell
                end

                for i = 1:length(fileName)
                    if (strcmp(fileName{1}(end-2:end),'txt') && strcmp(fileName{i}(end-2:end),'dms')) || ((strcmp(fileName{i}(end-2:end),'txt') && strcmp(fileName{1}(end-2:end),'dms')))

                        quest_temp = questdlg('载入选取文件中的.txt还是.tdms类型文件？','载入文件类型选择','.txt','.tdms','.txt');
                        if isequal(quest_temp,'.txt')
                            % 只保留.txt文件，去掉fileName_temp中没有被赋值的元素
                            fileName_temp = fileName;
                            for i = 1:length(fileName)
                                if strcmp(fileName{i}(end-2:end),'dms')
                                    fileName_temp{i} = [];
                                end
                            end
                            fileName_temp = fileName_temp(~cellfun('isempty',fileName_temp));
                            fileName = fileName_temp;
                        else
                            % 只保留.tdms文件，去掉fileName_temp中没有被赋值的元素
                            fileName_temp = fileName;
                            for i = 1:length(fileName)
                                if strcmp(fileName{i}(end-2:end),'txt')
                                    fileName_temp{i} = [];
                                end
                            end
                            fileName_temp = fileName_temp(~cellfun('isempty',fileName_temp));
                            fileName = fileName_temp;
                        end
                        app.select_filepath = filePath; 
                    end
                end


                app.select_fileName = fileName;
                app.select_filepath = filePath; 

            end
            
            % 确认文件被成功定位后 
                % 关闭"载入文件夹"按钮
            app.pushbutton_load_dir.BackgroundColor = [1 1 1];
    
                % 关闭启动按钮的开关和指示灯，但是不要跟新右边的结果显示框（只有当文件被正确读取载入后才更新）
            app.Switch_Start_Scan.Value = '关闭扫描';
            app.Lamp_Start_Scan.Color = [1,1,1];

                % 更新 "载入文件"显示框
            app.Chn_load_file_list.Items = app.select_fileName;
            app.Chn_load_file_list.Value = app.select_fileName{1,1};

                % 清空"扫描结果"显示框，为这一批次的扫描做准备
            update_scan_result_display(app,2);


        end

        % Button pushed function: pushbutton_load_dir
        function pushbutton_load_dirButtonPushed(app, event)
            app.pushbutton_load_dir.BackgroundColor = [0 1 0];

            % 这是文件选择框打开的位置
            if isempty(app.select_filepath)==1
                uigetdir_path_temp = 'C:\';
            else
                uigetdir_path_temp = app.select_filepath;
            end
            
            % 读取批次文件

            [filePath] = uigetdir(uigetdir_path_temp, '选择文件夹')

            if filePath == 0
                msgbox('文件夹载入有误！','错误','error')
                app.pushbutton_load_dir.BackgroundColor = [1 1 1];
                return
            else               
                file_name_txt_temp = dir(fullfile(filePath,'*.txt'));
                file_name_tdms_temp = dir(fullfile(filePath,'*.tdms'));
                file_name_txt = {file_name_txt_temp(:).name}
                file_name_tdms = {file_name_tdms_temp(:).name} 

                if (isempty(file_name_txt)==1) & (isempty(file_name_tdms)==1)
                    msgbox('文件夹中无规定格式的数据文件！','错误','error')
                    app.pushbutton_load_dir.BackgroundColor = [1 1 1];
                    return

                elseif (isempty(file_name_txt)==0) & (isempty(file_name_tdms)==0)
                    quest_temp = questdlg('选择读取文件夹的.txt还是.tdms类型文件？','载入文件类型选择','.txt','.tdms','.txt');
                    if isequal(quest_temp,'.txt')
                        app.select_fileName = file_name_txt;
                    else
                        app.select_fileName = file_name_tdms
                    end
                    app.select_filepath = filePath; 

                elseif isempty(file_name_txt_temp)==0
                    app.select_filepath = filePath; 
                    app.select_fileName = file_name_txt;

                else
                    app.select_filepath = filePath; 
                    app.select_fileName = file_name_tdms;
                end
            end   
    
            % 只有当正确读入新文件时才执行下面的操作
                % 关闭"载入文件夹"按钮
            app.pushbutton_load_file.BackgroundColor = [1 1 1];

                % 关闭启动按钮的开关和指示灯
            app.Switch_Start_Scan.Value = '关闭扫描';
            app.Lamp_Start_Scan.Color = [1,1,1];

                % 更新 "载入文件"显示框
            app.Chn_load_file_list.Items = app.select_fileName;
            app.Chn_load_file_list.Value = app.select_fileName{1,1};
                
                % 清空"扫描结果"显示框，为这一批次的扫描做准备
            update_scan_result_display(app,2);


        end

        % Value changed function: Switch_Start_Scan
        function Switch_Start_ScanValueChanged(app, event)
            value = app.Switch_Start_Scan.Value;

            % 二次确认框
            quest_temp = questdlg('是否进行文件扫描','文件扫描','启动','取消','取消');            
            if isequal(quest_temp,'取消')
                app.Switch_Start_Scan.Value = '关闭扫描';
                return
            else
                % 检测保存路径是否正确
                exit_temp = CheckPath(app,app.save_resul_path.Value,'dir');
                if exit_temp == 0
                    app.Switch_Start_Scan.Value = '关闭扫描';
                    return
                end
            end


            % 更新UI显示标注为：启动扫描
            app.Switch_Start_Scan.Value = '启动扫描';
            app.Lamp_Start_Scan.Color = [1 0 0]; %启动、或者没完成时是红灯
            
            setParInfo = app.pushbutton_Setting.UserData;

            % 清空"扫描结果"显示框，为这一次的扫描做准备
            update_scan_result_display(app,2);

            % 检查当前的待检测文件是否为空
            if isempty(app.select_fileName)==0 & isempty(app.select_filepath)==0
                
%-------------------------------------------------------------%
%---------------------------开始扫描---------------------------%
                num_normal_serious_suspected_num = cell(1, 3);
                num_normal_serious_suspected_num(:) = {0};   
                
                total_result_display = cell(1, 1); % 默认向列方向扩展

                serious_result_display = cell(1, 1);
                suspected_result_display = cell(1, 1);

                % 
                h = waitbar(round(1/length(app.select_fileName),4),'正在进行文件扫描','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
                setappdata(h,'canceling',0);              


                % 开始逐个的进行文件结果的扫描 - 每扫描一次就及时的更新右边的扫描结果框最上方的用+*表示正在进行统计
                for i = 1:length(app.select_fileName)

                    %初始化
                    num_normal_serious_suspected = {'正常文件：','疑似故障：','严重故障：'}

                    % 进度条
                    str = ['文件扫描中...',num2str(i/length(app.select_fileName)*100,'%0.2f'),'%'];
                    waitbar(i/length(app.select_fileName),h,str);

                    % 扫描进度框显示
                    app.text_Scan_Progress.Text = strcat('扫描进度：', num2str(i/length(app.select_fileName)*100,'%0.2f'), '%')

                    % 在载入文件框，显示当前正在扫描的文件
                    app.Chn_load_file_list.Value = app.select_fileName{i};

                    
                    % 扫描过程中可临时取消，此时仅显示当前达到的扫描进度
                    if getappdata(h,'canceling') % 如果在中途取消了扫描                         
                        delete(h);                    
                        msgbox('全域扫描已取消！','注意：','warn');
                        app.Switch_Start_Scan.Value = '关闭扫描';
                        app.Lamp_Start_Scan.Color = [0 0 1]; %只扫描了部分文件就中途停止——蓝灯
                        return
                    end

        %-----------------------------------------------------------------%
        %---------------------------单个文件扫描---------------------------%                    
                    if app.select_fileName{i}(end-2:end) == 'txt'% 对单个txt文件 进行扫描

                        FileScanningSingle_temp = FileScanningSingleTXT(app.ButtonGroup_scn_mode.SelectedObject.Text, ...
                            setParInfo.scn_channel, ...
                            setParInfo.scn_channel_max, ...
                            setParInfo.scn_serious_threshold, ...
                            setParInfo.scn_suspected_threshold, ...
                            setParInfo.set_scanning_sensitivity, ...
                            app.select_fileName{i}, ...
                            app.select_filepath)
  
                    elseif app.select_fileName{i}(end-2:end) == 'dms' % 对单个txt文件 进行扫描
                        FileScanningSingle_temp = FileScanningSingleTDMS(app.ButtonGroup_scn_mode.SelectedObject.Text, ...
                            setParInfo.scn_channel, ...
                            setParInfo.scn_channel_max, ...
                            setParInfo.scn_serious_threshold, ...
                            setParInfo.scn_suspected_threshold, ...
                            setParInfo.set_scanning_sensitivity, ...
                            app.select_fileName{i}, ...
                            app.select_filepath)
                            
                    else
    
                    end

                    num_normal_serious_suspected_num{FileScanningSingle_temp.status_label} = 1 + num_normal_serious_suspected_num{FileScanningSingle_temp.status_label};
                    num_normal_serious_suspected_num_str = cellfun(@num2str, num_normal_serious_suspected_num, 'UniformOutput', false);
                    for str_idx = 1:length(num_normal_serious_suspected_num_str)
                        num_nor_ser_sus_num_str_display{str_idx} = [num_normal_serious_suspected{str_idx} num_normal_serious_suspected_num_str{str_idx}];
                    end
                    
                    if isempty(total_result_display{1}) % 如果还没装过内容
                        total_result_display = cellstr(FileScanningSingle_temp.filename_sus_ser_display);
                    else
                        total_result_display = [total_result_display, FileScanningSingle_temp.filename_sus_ser_display];
                    end
                    
                    if FileScanningSingle_temp.status_label == 3
                        if isempty(serious_result_display{1}) % 如果还没装过内容
                            serious_result_display = cellstr(FileScanningSingle_temp.filename_sus_ser_display);
                        else
                            serious_result_display = [serious_result_display, FileScanningSingle_temp.filename_sus_ser_display];
                        end
                        
                    elseif FileScanningSingle_temp.status_label == 2
                        if isempty(suspected_result_display{1}) % 如果还没装过内容
                            suspected_result_display = cellstr(FileScanningSingle_temp.filename_sus_ser_display);
                        else
                            suspected_result_display = [suspected_result_display, FileScanningSingle_temp.filename_sus_ser_display];
                        end
    
                    end

        %---------------------------单个文件扫描---------------------------% 
        %-----------------------------------------------------------------%
                    
                    % 更新右边的扫描结果框
                    update_scan_result_display(app,1,num_nor_ser_sus_num_str_display,total_result_display,serious_result_display,suspected_result_display);


                    if i == length(app.select_fileName) % 扫描完成
                        app.Switch_Start_Scan.Value = '关闭扫描';
                        app.Lamp_Start_Scan.Color = [0 1 0]; % 扫描成功——绿灯 
                    end
        
                end
                
                % 保存扫描结果
                if app.ButtonGroup_save_set.SelectedObject.Text == '自动保存'
                    save_file_scan_result(app,'File_scan_results_',app.Chn_total_result.Items)
                end

                delete(h);
                msgbox('全域扫描诊断完成');

%---------------------------开始扫描---------------------------%
%-------------------------------------------------------------% 
            else % 如果检查到当前的待检测文件为空，就不进行扫描操作
                msgbox('待检测数据文件错误！','错误','error')
                app.Switch_Start_Scan.Value = '关闭扫描';
                app.Lamp_Start_Scan.Color = [1 1 1];  
            
            end


        end

        % Button pushed function: pushbutton_delete_filename
        function pushbutton_delete_filenameButtonPushed(app, event)
            if isempty(app.Chn_load_file_list.Value)==0
                % 不用找了，因为不会在同一个文件夹下存在同名的多个文件夹
                % 从app.select_fileName中删除已经选择的文件名，并清除app.select_fileName中的空行
                for j = 1:length(app.Chn_load_file_list.Value)
                    for i = 1:length(app.select_fileName)
                        if strcmp(app.select_fileName{1,i},app.Chn_load_file_list.Value(j))==1
                            app.select_fileName(i) = [];
                            app.select_fileName = app.select_fileName(~cellfun('isempty',app.select_fileName));
                            break
                        end
                    end
                end
                
            
                % 如果删除后，还剩有文件名，就正常更新 "载入文件"显示框，否则没文件时.Value 值应该为空
                app.Chn_load_file_list.Items = app.select_fileName;
                if isempty(app.select_fileName)==0
                    app.Chn_load_file_list.Value = app.select_fileName{1,1};
                else
                    app.Chn_load_file_list.Value = {};
                end

                % 将扫描启动等关闭，准备好下一次的扫描
                app.Lamp_Start_Scan.Color = [1 1 1];
            
            end
        end

        % Key press function: AMB_FSLUIFigure
        function AMB_FSLUIFigureKeyPress(app, event)
            key = event.Key;
            switch key 
                case 'delete'
                    pushbutton_delete_filenameButtonPushed(app,event);
            end
        end

        % Button pushed function: pushbutton_total_result
        function pushbutton_total_resultButtonPushed(app, event)
            % 判断是全选还是手选导出
            if app.CheckBox_total_result.Value == 1
                save_file_scan_result(app,'total_result_',app.Chn_total_result.Items)                
            else
                save_file_scan_result(app,'total_result_',app.Chn_total_result.Value)
            end

        end

        % Button pushed function: pushbutton_serious_result
        function pushbutton_serious_resultButtonPushed(app, event)
            % 判断是全选还是手选导出
            if app.CheckBox_serious_result.Value == 1
                save_file_scan_result(app,'serious_result_',app.Chn_serious_result.Items)                
            else
                save_file_scan_result(app,'serious_result_',app.Chn_serious_result.Value)
            end

        end

        % Button pushed function: pushbutton_suspected_result
        function pushbutton_suspected_resultButtonPushed(app, event)
            % 判断是全选还是手选导出
            if app.CheckBox_suspected_result.Value == 1
                save_file_scan_result(app,'suspected_result_',app.Chn_suspected_result.Items)                
            else
                save_file_scan_result(app,'suspected_result_',app.Chn_suspected_result.Value)
            end

        end

        % Button pushed function: pushbutton_Setting
        function pushbutton_Setting_Callback(app, event)
            
            app.CApp = SetPar(app);  %初始化参数读入
%             update_par_dis(app); % 初始化参数显示
                   
            
        end

        % Button pushed function: pushbutton_load_dir_2
        function pushbutton_load_dir_2ButtonPushed(app, event)

            if isempty(app.save_resul_path.Value)==1
                uigetdir_path_temp = 'C:\';
            else
                uigetdir_path_temp = app.select_filepath;
            end
            
            % 读取批次文件

            [filePath] = uigetdir(uigetdir_path_temp, '选择导出路径')

            if filePath == 0
                msgbox('导出路径选择有误！','错误','error')
                return
            else               
               app.save_resul_path.Value = filePath;
            end   

        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)

            value = app.TextArea.Value;
            cle_txt_cal(app);
            
        end

        % Button pushed function: pushbutton_delete_filename_2
        function pushbutton_delete_filename_2ButtonPushed(app, event)
            % 开始解析
            if isempty(app.TextArea.Value)==0 
                % 对app.TextArea.Value中的字符串，每2944个字符拆分为一组，依次放入cell数组中
                len = length(app.TextArea.Value{:});
                n = ceil(floor(len/2944));
        
                % 生成cell数组
                cellArray = cell(1,n);
        
                for i = 1:n
                    cellArray{i} = app.TextArea.Value{:}((i-1)*2944+1:i*2944);
                end
        
                % 显示
                app.Chn_load_file_list_2.Items = cellArray; 
            end
        end

        % Clicked callback: Chn_load_file_list_2
        function Chn_load_file_list_2Clicked(app, event)
            item = event.InteractionInformation.Item;

            if isempty(app.Chn_load_file_list_2.Value) == 0
                app.UITable.ColumnName = {'Label'; '名称'; '单位'; '倍数';
                    '数据点 1'; '数据点 2'; '数据点 3'; '数据点 4'; '数据点 5'; '数据点 6'; '数据点 7'; '数据点 8'; '数据点 9'; '数据点 10'; '数据点 11'; '数据点 12'; '数据点 13'; '数据点 14'; '数据点 15'; '数据点 16'; '数据点 17'; '数据点 18'; '数据点 19'; '数据点 20'; '数据点 21'; '数据点 22'; '数据点 23'; '数据点 24'; '数据点 25'; '数据点 26'; '数据点 27'; '数据点 28'; '数据点 29'; '数据点 30'; '数据点 31'; '数据点 32'; '数据点 33'; '数据点 34'; '数据点 35'; '数据点 36'; '数据点 37'; '数据点 38'; '数据点 39'; '数据点 40'; '数据点 41'; '数据点 42'; '数据点 43'; '数据点 44'; '数据点 45'; '数据点 46'; '数据点 47'; '数据点 48'; '数据点 49'; '数据点 50'; '数据点 51'; '数据点 52'; '数据点 53'; '数据点 54'; '数据点 55'; '数据点 56'; '数据点 57'; '数据点 58'; '数据点 59'; '数据点 60'; '数据点 61'; '数据点 62'; '数据点 63'; '数据点 64'; '数据点 65'; '数据点 66'; '数据点 67'; '数据点 68'; '数据点 69'};
                dispaly_data = TXT_Calculator(app.Chn_load_file_list_2.Value);
                
                app.tt_secEditField.Value = dispaly_data.tt_sec;
                app.tt_100uEditField.Value =  num2str(dispaly_data.tt_100u_sec);
                app.EditField.Value = '------';

                UITable_label = {'EXC','扫频输入','1','1/2048';
                                'X1','位移X1','微米','1/32';
                                'Y1','位移Y1','微米','1/32';
                                'Z1','位移Z1','微米','1/32';
                                'X2','位移X2','微米','1/32';
                                'Y2','位移Y2','微米','1/32';
                                'PX1','功放电流','安培','1/256';
                                'NX1','功放电流','安培','1/256';
                                'PY1','功放电流','安培','1/256';
                                'NY1','功放电流','安培','1/256';
                                'PZ','功放电流','安培','1/256';
                                'NZ','功放电流','安培','1/256';
                                'PX2','功放电流','安培','1/256';
                                'NX2','功放电流','安培','1/256';
                                'PY2','功放电流','安培','1/256';
                                'NY2','功放电流','安培','1/256'};
                app.UITable.Data = horzcat(UITable_label,num2cell(dispaly_data.AD_RAW));

                app.speed_hzEditField.Value = num2str(dispaly_data.speed_hz);
                app.rotor_angle_radEditField.Value = num2str(dispaly_data.rotor_angle_rad);
                app.EditField_2.Value = '------';


            end

        end

        % Button pushed function: pushbutton_delete_filename_3
        function pushbutton_delete_filename_3ButtonPushed(app, event)

            % 这是文件选择框打开的位置
            if isempty(app.select_filepath)==1
                uigetfile_path_temp = 'C:\';
            else
                uigetfile_path_temp = app.select_filepath;
            end

            % 读取批次文件
            [fileName,filePath] = uigetfile({'*.txt'},'选择文件',uigetfile_path_temp,'MultiSelect','off');

%             if strcmp(class(fileName), 'double')||strcmp(class(filePath), 'double')
            if isa(fileName, 'double')||isa(filePath, 'double')
                msgbox('文件载入有误！','错误','error')
                app.pushbutton_load_file.BackgroundColor = [1 1 1];
                return
            else
                                
                % 根据路径读取文件
                fileID = fopen([filePath,fileName],'r');
                % 读取文件中的所有内容
                fileContent = fread(fileID,inf,'*char');
                % 关闭文件
                fclose(fileID);
                % 将读取的内容赋值给app.TextArea.Value
                app.TextArea.Value = fileContent';
                cle_txt_cal(app);   

            end
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AMB_FSLUIFigure = uifigure('Visible', 'off');
            app.AMB_FSLUIFigure.Name = 'AMB_FSL';
            app.AMB_FSLUIFigure.Icon = fullfile(pathToMLAPP, 'icon', 'AMB_FSL 软件封面.png');
            app.AMB_FSLUIFigure.KeyPressFcn = createCallbackFcn(app, @AMB_FSLUIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.AMB_FSLUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '主界面';
            app.text_Setting = uilabel(app.Tab);
            app.text_Setting.Tag = 'text_Setting';
            app.text_Setting.Text = {'AMB数据文件'; '扫描软件'};
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '文件扫描';
            app.Panel_4 = uipanel(app.Panel);
            app.Panel_4.Title = '扫描参数';
            app.pushbutton_Setting = uibutton(app.Panel_4, 'push');
            app.pushbutton_Setting.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Setting_Callback, true);
            app.pushbutton_Setting.Tag = 'pushbutton_Setting';
            app.pushbutton_Setting.Tooltip = {'1.手动设置参数'; '2.载入已配置好的.csv格式参数文件'};
            app.pushbutton_Setting.Text = '参数设置';
            app.Panel_5 = uipanel(app.Panel);
            app.Panel_5.Title = '结果保存';
            app.ButtonGroup_save_set = uibuttongroup(app.Panel_5);
            app.ButtonGroup_save_set.Title = '结果保存方式';
            app.Button__save_set = uiradiobutton(app.ButtonGroup_save_set);
            app.Button__save_set.Tooltip = {'扫描结束后自动将所有扫描结果导出保存'; '保存文件title为“File_scan_results_”'};
            app.Button__save_set.Text = '自动保存';
            app.Button__save_set.Value = true;
            app.Button__save_set_2 = uiradiobutton(app.ButtonGroup_save_set);
            app.Button__save_set_2.Tooltip = {'扫描结束后需手动选取要保存的扫描结果'};
            app.Button__save_set_2.Text = '手动保存';
            app.Panel_6 = uipanel(app.Panel);
            app.Panel_6.Title = '扫描启动';
            app.Switch_Start_Scan = uiswitch(app.Panel_6, 'slider');
            app.Switch_Start_Scan.Items = {'关闭扫描', '启动扫描'};
            app.Switch_Start_Scan.ValueChangedFcn = createCallbackFcn(app, @Switch_Start_ScanValueChanged, true);
            app.Switch_Start_Scan.Tooltip = {'启动扫描前，请先确认导出路径是否正确'};
            app.Switch_Start_Scan.Value = '关闭扫描';
            app.Lamp_Start_Scan = uilamp(app.Panel_6);
            app.Lamp_Start_Scan.Tooltip = {'启动扫描前，请先确认导出路径是否正确'};
            app.ButtonGroup_scn_mode = uibuttongroup(app.Panel_6);
            app.ButtonGroup_scn_mode.Title = '模式选择';
            app.Button_scn_mode = uiradiobutton(app.ButtonGroup_scn_mode);
            app.Button_scn_mode.Text = '快速扫描';
            app.Button_scn_mode.Value = true;
            app.Button_scn_mode_2 = uiradiobutton(app.ButtonGroup_scn_mode);
            app.Button_scn_mode_2.Tooltip = {'暂未开发'};
            app.Button_scn_mode_2.Text = '模式-2';
            app.Button_scn_mode_3 = uiradiobutton(app.ButtonGroup_scn_mode);
            app.Button_scn_mode_3.Tooltip = {'暂未开发'};
            app.Button_scn_mode_3.Text = '模式-3';
            app.Panel_7 = uipanel(app.Panel);
            app.Panel_7.Title = '文件载入';
            app.pushbutton_load_dir = uibutton(app.Panel_7, 'push');
            app.pushbutton_load_dir.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_load_dirButtonPushed, true);
            app.pushbutton_load_dir.Tag = 'pushbutton_ExportData';
            app.pushbutton_load_dir.Icon = fullfile(pathToMLAPP, 'icon', '载入文件夹.png');
            app.pushbutton_load_dir.Text = '载入文件夹';
            app.pushbutton_load_file = uibutton(app.Panel_7, 'push');
            app.pushbutton_load_file.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_load_fileButtonPushed, true);
            app.pushbutton_load_file.Tag = 'pushbutton_ExportData';
            app.pushbutton_load_file.Icon = fullfile(pathToMLAPP, 'icon', '载入文件.png');
            app.pushbutton_load_file.Text = '载入文件';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_8 = uipanel(app.Panel_2);
            app.Panel_8.Title = '扫描结果显示框';
            app.Panel_10 = uipanel(app.Panel_8);
            app.Panel_10.Title = '整体结果';
            app.Chn_num_normal_serious_suspected = uilistbox(app.Panel_10);
            app.Chn_num_normal_serious_suspected.Items = {'正常文件：', '疑似故障：', '严重故障：'};
            app.Chn_num_normal_serious_suspected.Multiselect = 'on';
            app.Chn_num_normal_serious_suspected.Tag = 'ListBox_Chn';
            app.Chn_num_normal_serious_suspected.Tooltip = {'已扫描完的文件中，正常/疑似/严重故障文件的个数'};
            app.Chn_num_normal_serious_suspected.Value = {};
            app.Chn_total_result = uilistbox(app.Panel_10);
            app.Chn_total_result.Items = {};
            app.Chn_total_result.Multiselect = 'on';
            app.Chn_total_result.Tag = 'ListBox_Chn';
            app.Chn_total_result.Tooltip = {'1.第一位百分数：该文件中，疑似故障点占比'; '2.第二位百分数：该文件中，严重故障点占比'};
            app.Chn_total_result.Value = {};
            app.CheckBox_total_result = uicheckbox(app.Panel_10);
            app.CheckBox_total_result.Text = '导出-全选';
            app.CheckBox_total_result.Value = true;
            app.pushbutton_total_result = uibutton(app.Panel_10, 'push');
            app.pushbutton_total_result.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_total_resultButtonPushed, true);
            app.pushbutton_total_result.Tag = 'pushbutton_ExportData';
            app.pushbutton_total_result.Tooltip = {'保存文件title为“total_result_”'};
            app.pushbutton_total_result.Text = '导出';
            app.text_Scan_Progress = uilabel(app.Panel_10);
            app.text_Scan_Progress.Tag = 'text_Setting';
            app.text_Scan_Progress.Text = '扫描进度：';
            app.Panel_11 = uipanel(app.Panel_8);
            app.Panel_11.Title = '严重故障-文件列表';
            app.Chn_serious_result = uilistbox(app.Panel_11);
            app.Chn_serious_result.Items = {};
            app.Chn_serious_result.Multiselect = 'on';
            app.Chn_serious_result.Tag = 'ListBox_Chn';
            app.Chn_serious_result.Tooltip = {'1.第一位百分数：该文件中，疑似故障点占比'; '2.第二位百分数：该文件中，严重故障点占比'};
            app.Chn_serious_result.Value = {};
            app.CheckBox_serious_result = uicheckbox(app.Panel_11);
            app.CheckBox_serious_result.Text = '导出-全选';
            app.CheckBox_serious_result.Value = true;
            app.pushbutton_serious_result = uibutton(app.Panel_11, 'push');
            app.pushbutton_serious_result.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_serious_resultButtonPushed, true);
            app.pushbutton_serious_result.Tag = 'pushbutton_ExportData';
            app.pushbutton_serious_result.Tooltip = {'保存文件title为“serious_result_”'};
            app.pushbutton_serious_result.Text = '导出';
            app.Panel_12 = uipanel(app.Panel_8);
            app.Panel_12.Title = '疑似故障-文件列表';
            app.Chn_suspected_result = uilistbox(app.Panel_12);
            app.Chn_suspected_result.Items = {};
            app.Chn_suspected_result.Multiselect = 'on';
            app.Chn_suspected_result.Tag = 'ListBox_Chn';
            app.Chn_suspected_result.Tooltip = {'1.第一位百分数：该文件中，疑似故障点占比'; '2.第二位百分数：该文件中，严重故障点占比'};
            app.Chn_suspected_result.Value = {};
            app.CheckBox_suspected_result = uicheckbox(app.Panel_12);
            app.CheckBox_suspected_result.Text = '导出-全选';
            app.CheckBox_suspected_result.Value = true;
            app.pushbutton_suspected_result = uibutton(app.Panel_12, 'push');
            app.pushbutton_suspected_result.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_suspected_resultButtonPushed, true);
            app.pushbutton_suspected_result.Tag = 'pushbutton_ExportData';
            app.pushbutton_suspected_result.Tooltip = {'保存文件title为“suspected_result_”'};
            app.pushbutton_suspected_result.Text = '导出';
            app.Panel_9 = uipanel(app.Panel_2);
            app.Panel_9.Title = '载入文件显示框';
            app.Panel_13 = uipanel(app.Panel_9);
            app.Panel_13.Title = '载入待扫描文件';
            app.Chn_load_file_list = uilistbox(app.Panel_13);
            app.Chn_load_file_list.Items = {};
            app.Chn_load_file_list.Multiselect = 'on';
            app.Chn_load_file_list.Tag = 'ListBox_Chn';
            app.Chn_load_file_list.Value = {};
            app.pushbutton_delete_filename = uibutton(app.Panel_13, 'push');
            app.pushbutton_delete_filename.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_delete_filenameButtonPushed, true);
            app.pushbutton_delete_filename.Tag = 'pushbutton_ExportData';
            app.pushbutton_delete_filename.Text = '删除';
            app.Panel_14 = uipanel(app.Panel_9);
            app.Panel_14.Title = '载入参数';
            app.Chn_par = uilistbox(app.Panel_14);
            app.Chn_par.Items = {'﻿scn_channel : 2,3,5,6,4'};
            app.Chn_par.Multiselect = 'on';
            app.Chn_par.Tag = 'ListBox_Chn';
            app.Chn_par.Value = {};
            app.Panel_3 = uipanel(app.Tab);
            app.pushbutton_load_dir_2 = uibutton(app.Panel_3, 'push');
            app.pushbutton_load_dir_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_load_dir_2ButtonPushed, true);
            app.pushbutton_load_dir_2.Tag = 'pushbutton_ExportData';
            app.pushbutton_load_dir_2.Icon = fullfile(pathToMLAPP, 'icon', '载入文件夹.png');
            app.pushbutton_load_dir_2.Text = '导出路径选择';
            app.save_resul_path = uieditfield(app.Panel_3, 'text');
            app.save_resul_path.Tag = 'edit_MaxLimit';
            app.save_resul_path.Editable = 'off';
            app.save_resul_path.Value = '.\Save Results';
            app.text3 = uilabel(app.Panel_3);
            app.text3.Tag = 'text3';
            app.text3.Text = '导出路径：';
            app.TXTTab = uitab(app.TabGroup);
            app.TXTTab.Title = 'TXT数据段计算器';
            app.Panel_15 = uipanel(app.TXTTab);
            app.Panel_15.Title = '扫描结果显示框';
            app.Panel_17 = uipanel(app.Panel_15);
            app.Panel_17.Title = '整体结果';
            app.text_Scan_Progress_2 = uilabel(app.Panel_17);
            app.text_Scan_Progress_2.Tag = 'text_Setting';
            app.text_Scan_Progress_2.Text = '扫描进度：';
            app.tt_secEditFieldLabel = uilabel(app.Panel_17);
            app.tt_secEditFieldLabel.Text = {'1-8：8字符'; '（tt_sec）'};
            app.tt_secEditField = uieditfield(app.Panel_17, 'text');
            app.tt_secEditField.Editable = 'off';
            app.tt_secEditField.Tooltip = {'32位无符号整型，低位在前；'; '第一个采样点装入时，控制器内部1970-1-1 0:0:0到当前的秒数'};
            app.EditFieldLabel = uilabel(app.Panel_17);
            app.EditFieldLabel.Text = {'13-28：16字符'; '（保留）'};
            app.EditField = uieditfield(app.Panel_17, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Tooltip = {'未定义'};
            app.tt_100uEditFieldLabel = uilabel(app.Panel_17);
            app.tt_100uEditFieldLabel.Text = {'9-12：4字符'; '（tt_100u）'};
            app.tt_100uEditField = uieditfield(app.Panel_17, 'text');
            app.tt_100uEditField.Editable = 'off';
            app.tt_100uEditField.Tooltip = {'16位无符号整型，低位在前；'; '第一个采样点装入时，控制器内部100微秒的时间tick'};
            app.rotor_angle_radEditFieldLabel = uilabel(app.Panel_17);
            app.rotor_angle_radEditFieldLabel.Text = {'2917-2924：8字符'; '（rotor_angle_rad）'};
            app.rotor_angle_radEditField = uieditfield(app.Panel_17, 'text');
            app.rotor_angle_radEditField.Editable = 'off';
            app.rotor_angle_radEditField.Tooltip = {'转子角度，低位在前，IEEE754 FLOAT，单位（RAD）(仅当有传感器且工作正常时)'};
            app.EditField_2Label = uilabel(app.Panel_17);
            app.EditField_2Label.Text = {'2925-2944：20字符'; '（保留）'};
            app.EditField_2 = uieditfield(app.Panel_17, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Tooltip = {'未定义'};
            app.speed_hzEditFieldLabel = uilabel(app.Panel_17);
            app.speed_hzEditFieldLabel.Text = {'2909-2916：8字符'; '（speed_hz）'};
            app.speed_hzEditField = uieditfield(app.Panel_17, 'text');
            app.speed_hzEditField.Editable = 'off';
            app.speed_hzEditField.Tooltip = {'转速，低位在前，IEEE754 FLOAT，单位Hz；'};
            app.UITable = uitable(app.Panel_17);
            app.UITable.ColumnName = {'Label'; '名称'; '单位'; '倍数'; '列 5'; '列 6'; '列 7'; '列 8'; '列 9'; '列 10'; '列 11'; '列 12'; '列 13'; '列 14'; '列 15'; '列 16'; '列 17'; '列 18'; '列 19'; '列 20'; '列 21'; '列 22'; '列 23'; '列 24'; '列 25'; '列 26'; '列 27'; '列 28'; '列 29'; '列 30'; '列 31'; '列 32'; '列 33'; '列 34'; '列 35'; '列 36'; '列 37'; '列 38'; '列 39'; '列 40'; '列 41'; '列 42'; '列 43'; '列 44'; '列 45'; '列 46'; '列 47'; '列 48'; '列 49'};
            app.UITable.RowName = {};
            app.rotor_angle_radLabel = uilabel(app.Panel_17);
            app.rotor_angle_radLabel.Text = {'29-2908：65*44字符'; '（rotor_angle_rad）'};
            app.Panel_16 = uipanel(app.TXTTab);
            app.Panel_16.Title = '载入文件显示框';
            app.Panel_18 = uipanel(app.Panel_16);
            app.Panel_18.Title = '数据分段解析结果';
            app.Chn_load_file_list_2 = uilistbox(app.Panel_18);
            app.Chn_load_file_list_2.Items = {};
            app.Chn_load_file_list_2.Multiselect = 'on';
            app.Chn_load_file_list_2.Tag = 'ListBox_Chn';
            app.Chn_load_file_list_2.Tooltip = {'每行为一个2944字节数据段'; '（数据末端不对齐是因为字距问题）'};
            app.Chn_load_file_list_2.ClickedFcn = createCallbackFcn(app, @Chn_load_file_list_2Clicked, true);
            app.Chn_load_file_list_2.Value = {};
            app.txtPanel = uipanel(app.Panel_16);
            app.txtPanel.Title = '粘贴或导入一段txt数据(数据头必须为一条数据的起始位)';
            app.pushbutton_delete_filename_2 = uibutton(app.txtPanel, 'push');
            app.pushbutton_delete_filename_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_delete_filename_2ButtonPushed, true);
            app.pushbutton_delete_filename_2.Tag = 'pushbutton_ExportData';
            app.pushbutton_delete_filename_2.Text = '解析';
            app.TextArea = uitextarea(app.txtPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.pushbutton_delete_filename_3 = uibutton(app.txtPanel, 'push');
            app.pushbutton_delete_filename_3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_delete_filename_3ButtonPushed, true);
            app.pushbutton_delete_filename_3.Tag = 'pushbutton_ExportData';
            app.pushbutton_delete_filename_3.Text = '导入';
            % Show the figure after all components are created
            app.AMB_FSLUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AMB_FSL_3_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AMB_FSLUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AMB_FSLUIFigure)
        end
    end
end