classdef SetPar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        Tab_2                     matlab.ui.container.Tab
        text3_5                   matlab.ui.control.Label
        scn_channel_max           matlab.ui.control.EditField
        text3_3                   matlab.ui.control.Label
        set_scanning_sensitivity  matlab.ui.control.EditField
        scn_channel               matlab.ui.control.EditField
        text3                     matlab.ui.control.Label
        Tab                       matlab.ui.container.Tab
        text3_7                   matlab.ui.control.Label
        set_scanning_density      matlab.ui.control.EditField
        text3_6                   matlab.ui.control.Label
        scn_serious_threshold     matlab.ui.control.EditField
        text3_2                   matlab.ui.control.Label
        scn_suspected_threshold   matlab.ui.control.EditField
        pushbutton_Return_2       matlab.ui.control.Button
        pushbutton_Return         matlab.ui.control.Button
    end

    properties (Access = private)
        FApp % Description
                
        sel_par_filepath; % 被选择的参数文件所在路径
        sel_par_fileName; % 被选着的具体文件名
    end
    
    methods (Access = private)
        
        function SetDataInfo = SetData(app,SetDataInfo)
            %设置数据,读取模式，用于子程序初始化
            if nargin == 2
            % 待检通道
                app.scn_channel.Value = SetDataInfo.scn_channel;
    
                % 保护间隙
                app.scn_channel_max.Value = SetDataInfo.scn_channel_max;
    
                % 故障下界
                app.scn_suspected_threshold.Value = SetDataInfo.scn_suspected_threshold;
    
                % 故障上界
                app.scn_serious_threshold.Value = SetDataInfo.scn_serious_threshold;
    
                % 敏感阈度
                app.set_scanning_sensitivity.Value = SetDataInfo.set_scanning_sensitivity;
    
                % 扫描密度
                app.set_scanning_density.Value = SetDataInfo.set_scanning_density;
      
            %获取数据，mode = 2
            else
                % 自检参数是否设置正确（只检测逻辑）
                parameter_status = set_parameter_check(app); % 自带错误弹窗
                if parameter_status ~= 1 % 如果参数设置错误              
                    app.Switch_Start_Scan.Value = '关闭扫描';
                    app.Lamp_Start_Scan.Color = [1 1 1]; 
                    return
                end
                scn_channel = app.scn_channel.Value;
                scn_channel_max = app.scn_channel_max.Value;
                scn_suspected_threshold = app.scn_suspected_threshold.Value;
                scn_serious_threshold = app.scn_serious_threshold.Value;
                set_scanning_sensitivity = app.set_scanning_sensitivity.Value;
                set_scanning_density = app.set_scanning_density.Value; 
                           
                
                SetDataInfo = struct('scn_channel',scn_channel,'scn_channel_max',scn_channel_max,...
                'scn_suspected_threshold',scn_suspected_threshold,'scn_serious_threshold',scn_serious_threshold,...
                'set_scanning_sensitivity',set_scanning_sensitivity,...
                'set_scanning_density',set_scanning_density);

                % 将设置好的参数保存到配置文件：SetData.csv
                SetDataInfo_temp = struct('scn_channel',str2num(scn_channel),'scn_channel_max',str2num(scn_channel_max),...
                'scn_suspected_threshold',str2num(scn_suspected_threshold),'scn_serious_threshold',str2num(scn_serious_threshold),...
                'set_scanning_sensitivity',str2num(set_scanning_sensitivity),...
                'set_scanning_density',str2num(set_scanning_density));

                dataValues = struct2cell(SetDataInfo_temp);
                namesValues = fieldnames(SetDataInfo_temp);

                % 设置配置文件路径
                configFilePath = '.\config\SetData_temp.csv';

                % 打开文件以进行写入
                fileID = fopen(configFilePath, 'wt');

                % 检查文件是否成功打开
                if fileID == -1
                    error('无法打开文件进行写入。');
                end
                
                % 遍历每一行数据并写入CSV文件
                for row = 1:size(dataValues, 1)
                    % 取出当前行的数据
                    namesData = namesValues{row};
                    rowData = dataValues{row};
                    
                    fprintf(fileID, '%s,', namesData);
                    % 写入CSV文件的第row行，第2到(end)列
                    if length(rowData) > 1
                        fprintf(fileID, '%f,', rowData(1:end-1));
                    end
                    fprintf(fileID, '%f\n', rowData(end));
                end
                
                % 关闭文件
                fclose(fileID);
    
                app.pushbutton_Return.UserData = SetDataInfo ;
                % 更新主界面的参数显示
                update_par_dis(app);

            end
        end

        %用于2.0的参数设置功能
        function parameter_status = set_parameter_check(app) % 自动检查参数的设置是否合理（以待检通道的参数为基准）：返回为1是正常的，非1为异常
            % 状态代码
            parameter_status = '1'; % 1：参数输入正确
            % 代码说明(带上分行符)
            parameter_status_Interpretation = ['1：参数输入正确',newline,...
                                               '2：输入参数格式错误',newline,...
                                               '3：“保护间隙”个数与“待检通道”个数不符合',newline,...
                                               '4：“故障下界”个数与“待检通道”个数不符合',newline,...
                                               '5：“故障上界”个数与“待检通道”个数不符合',newline,...
                                               '6：“敏感阈度”个数不为2',newline,...
                                               '7：“扫描密度”个数不为1',newline];
            try
                scn_channel = strsplit(app.scn_channel.Value,',');
                scn_channel = str2double(scn_channel) % 如果原始字符串包含非数字字符或空格，str2double 会在那些位置生成 NaN（Not a Number）
                scn_channel_max = strsplit(app.scn_channel_max.Value,',');
                scn_channel_max = str2double(scn_channel_max)
                scn_suspected_threshold = strsplit(app.scn_suspected_threshold.Value,',');
                scn_suspected_threshold = str2double(scn_suspected_threshold)
                scn_serious_threshold = strsplit(app.scn_serious_threshold.Value,',');
                scn_serious_threshold = str2double(scn_serious_threshold)
                set_scanning_sensitivity = strsplit(app.set_scanning_sensitivity.Value,',');
                set_scanning_sensitivity = str2double(set_scanning_sensitivity)
                set_scanning_density = strsplit(app.set_scanning_density.Value,',');
                set_scanning_density = str2double(set_scanning_density)
            
                % 检测是否有非数字字符
                if any(isnan(scn_channel)) || any(isnan(scn_channel_max)) || any(isnan(scn_suspected_threshold)) || any(isnan(scn_serious_threshold)) || any(isnan(set_scanning_sensitivity))
                    parameter_status = [parameter_status,'/2'];
                else
                    if length(scn_channel) ~= length(scn_channel_max)
                        parameter_status = [parameter_status,'/3'];
                    end
                    if length(scn_channel) ~= length(scn_suspected_threshold)
                        parameter_status = [parameter_status,'/4'];
                    end
                    if length(scn_channel) ~= length(scn_serious_threshold)
                        parameter_status = [parameter_status,'/5'];
                    end
                    if length(set_scanning_sensitivity) ~= 2
                        parameter_status = [parameter_status,'/6'];
                    end
                    if length(set_scanning_density) ~= 1
                        parameter_status = [parameter_status,'/7'];
                    end      
            
                end
            
                if length(parameter_status) > 1
                    % 在msgbox弹窗中显示错误信息以及错误代码和错误代码说明
                    msgbox(['参数输入错误:',newline,parameter_status_Interpretation,newline,'错误代码：',parameter_status(3:end)],'错误','error');   
                else
                    parameter_status = str2double(parameter_status)
                end
            
            catch
                % 在msgbox弹窗中显示错误信息以及错误代码和错误代码说明
                msgbox('参数输入错误，请检查输入：','错误','error');   
            end
        end

        % 2024.1.18 用于更新参数显示框
        function update_par_dis(app)
%             fieldNamesCell = {{'scn_channel : 2…'} {'scn_channel_max…'}  {'scn_suspected_t…'}    {'scn_serious_thr…'}    {'set_scanning_se…'}    {'set_scanning_de…'}}
            fieldNamesCell = {  
           '待检通道';
           '保护间隙';
           '故障下界';
           '故障上界';
           '敏感阈度';
           '扫描密度'            
            }

            structValuesCell = struct2cell(app.pushbutton_Return.UserData);
    
            % 初始化一个新的cell数组
            concatenatedCell = cell(size(fieldNamesCell, 1), 1);
            % 遍历每一行并进行拼接
            for row = 1:size(fieldNamesCell, 1)
                % 获取当前行的field name和对应的struct value
                fieldName = fieldNamesCell{row};
                structValue = structValuesCell{row};
                % 使用'_:_'连接并存储到新的cell数组
                concatenatedCell{row} = [fieldName, ' : ', structValue];
            end
    
            app.FApp.Chn_par.Items = concatenatedCell;
            app.FApp.Chn_par.Value = {};
    
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.FApp = mainapp;
            SetDataInfo = app.FApp.pushbutton_Setting.UserData;
            SetDataInfo = SetData(app,SetDataInfo);
            
        end

        % Button pushed function: pushbutton_Return
        function pushbutton_ReturnButtonPushed(app, event)
            SetData(app);
            app.FApp.pushbutton_Setting.UserData = app.pushbutton_Return.UserData;

            delete(app);
        end

        % Button pushed function: pushbutton_Return_2
        function pushbutton_Return_2ButtonPushed(app, event)
            
            % 这是文件选择框打开的位置
            if isempty(app.sel_par_filepath)==1
                uigetfile_path_temp = 'C:\';
            else
                uigetfile_path_temp = app.select_filepath;
            end

            % 读取批次文件
            [fileName,filePath] = uigetfile({'*.csv'},'选择参数文件',uigetfile_path_temp,'MultiSelect','off');

            if isa(fileName, 'double')||isa(filePath, 'double')
                msgbox('文件载入有误！','错误','error')
                app.pushbutton_load_file.BackgroundColor = [1 1 1];
                return
            else        
                % 载入参数文件
                csvFilePath = fullfile(filePath, fileName);
                
                % 读取CSV文件
                csvData = importdata(csvFilePath);
                
                % 获取对应列的索引
                scn_channel_idx = find(strcmp(csvData.textdata, 'scn_channel'));
                scn_channel_max_idx = find(strcmp(csvData.textdata, 'scn_channel_max'));
                scn_suspected_threshold_idx = find(strcmp(csvData.textdata, 'scn_suspected_threshold'));
                scn_serious_threshold_idx = find(strcmp(csvData.textdata, 'scn_serious_threshold'));
                set_scanning_sensitivity_idx = find(strcmp(csvData.textdata, 'set_scanning_sensitivity'));
                set_scanning_density_idx = find(strcmp(csvData.textdata, 'set_scanning_density'));       
    
                
                % 获取对应列的数据（排除 NaN）
                scn_channel_data = csvData.data(scn_channel_idx, :);
                scn_channel_max_data = csvData.data(scn_channel_max_idx, :);
                scn_suspected_threshold_data = csvData.data(scn_suspected_threshold_idx, :);
                scn_serious_threshold_data = csvData.data(scn_serious_threshold_idx, :);
                set_scanning_sensitivity_data = csvData.data(set_scanning_sensitivity_idx, :);
                set_scanning_density_data = csvData.data(set_scanning_density_idx, :);
                
                scn_channel_data = scn_channel_data(~isnan(scn_channel_data));
                scn_channel_max_data = scn_channel_max_data(~isnan(scn_channel_max_data));
                scn_suspected_threshold_data = scn_suspected_threshold_data(~isnan(scn_suspected_threshold_data));
                scn_serious_threshold_data = scn_serious_threshold_data(~isnan(scn_serious_threshold_data));
                set_scanning_sensitivity_data = set_scanning_sensitivity_data(~isnan(set_scanning_sensitivity_data));
                set_scanning_density_data = set_scanning_density_data(~isnan(set_scanning_density_data));            
                
                % 将数值数据转换为字符串
                scn_channel_str = sprintf('%g,', scn_channel_data);
                scn_channel_max_str = sprintf('%g,', scn_channel_max_data);
                scn_suspected_threshold_str = sprintf('%g,', scn_suspected_threshold_data);
                scn_serious_threshold_str = sprintf('%g,', scn_serious_threshold_data);
                set_scanning_sensitivity_str = sprintf('%g,', set_scanning_sensitivity_data);
                set_scanning_density_str = sprintf('%g,', set_scanning_density_data);
    
                app.scn_channel.Value = scn_channel_str(1:end-1);  % 移除末尾的逗号
                app.scn_channel_max.Value = scn_channel_max_str(1:end-1);  % 移除末尾的逗号
                app.scn_suspected_threshold.Value = scn_suspected_threshold_str(1:end-1);  % 移除末尾的逗号
                app.scn_serious_threshold.Value = scn_serious_threshold_str(1:end-1);  % 移除末尾的逗号
                app.set_scanning_sensitivity.Value = set_scanning_sensitivity_str(1:end-1);  % 移除末尾的逗号
                app.set_scanning_density.Value = set_scanning_density_str(1:end-1);  % 移除末尾的逗号

            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '文件扫描-参数设置';
            app.pushbutton_Return = uibutton(app.Panel, 'push');
            app.pushbutton_Return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_ReturnButtonPushed, true);
            app.pushbutton_Return.Tag = 'pushbutton_Return';
            app.pushbutton_Return.Text = '保存';
            app.pushbutton_Return_2 = uibutton(app.Panel, 'push');
            app.pushbutton_Return_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Return_2ButtonPushed, true);
            app.pushbutton_Return_2.Tag = 'pushbutton_Return';
            app.pushbutton_Return_2.Text = {'载入参数'; '文件'};
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '通用设置';
            app.text3 = uilabel(app.Tab_2);
            app.text3.Tag = 'text3';
            app.text3.Text = '待检通道';
            app.scn_channel = uieditfield(app.Tab_2, 'text');
            app.scn_channel.Tag = 'edit_MaxLimit';
            app.scn_channel.Tooltip = {'顺序为X1/Y1/X2/Y2/Z'};
            app.scn_channel.Value = '2,3,5,6,4';
            app.set_scanning_sensitivity = uieditfield(app.Tab_2, 'text');
            app.set_scanning_sensitivity.Tag = 'edit_MaxLimit';
            app.set_scanning_sensitivity.Tooltip = {'疑似故障点+严重故障点占比超过第一个值识别为疑似故障文件'; '严重故障点占比超过第一个值识别为严重故障文件（优先）'};
            app.set_scanning_sensitivity.Value = '0.05,0.005';
            app.text3_3 = uilabel(app.Tab_2);
            app.text3_3.Tag = 'text3';
            app.text3_3.Text = '敏感阈度';
            app.scn_channel_max = uieditfield(app.Tab_2, 'text');
            app.scn_channel_max.Tag = 'edit_MaxLimit';
            app.scn_channel_max.Tooltip = {'顺序为X1/Y1/X2/Y2/Z分别对应的保护间隙'};
            app.scn_channel_max.Value = '0.2,0.2,0.2,0.2,0.15';
            app.text3_5 = uilabel(app.Tab_2);
            app.text3_5.Tag = 'text3';
            app.text3_5.Text = '保护间隙';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '高级设置';
            app.scn_suspected_threshold = uieditfield(app.Tab, 'text');
            app.scn_suspected_threshold.Tag = 'edit_MaxLimit';
            app.scn_suspected_threshold.Value = '0.5,0.5,0.5,0.5,0.5';
            app.text3_2 = uilabel(app.Tab);
            app.text3_2.Tag = 'text3';
            app.text3_2.Text = '故障下界';
            app.scn_serious_threshold = uieditfield(app.Tab, 'text');
            app.scn_serious_threshold.Tag = 'edit_MaxLimit';
            app.scn_serious_threshold.Value = '0.8,0.8,0.8,0.8,0.8';
            app.text3_6 = uilabel(app.Tab);
            app.text3_6.Tag = 'text3';
            app.text3_6.Text = '故障上界';
            app.set_scanning_density = uieditfield(app.Tab, 'text');
            app.set_scanning_density.Tag = 'edit_MaxLimit';
            app.set_scanning_density.Value = '100';
            app.text3_7 = uilabel(app.Tab);
            app.text3_7.Tag = 'text3';
            app.text3_7.Text = '扫描密度';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetPar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end