classdef TDMSInfo3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel_6                   matlab.ui.container.Panel
        scanning_sensitivity      matlab.ui.control.EditField
        text3_5                   matlab.ui.control.Label
        global_scanning_density   matlab.ui.control.EditField
        text3_4                   matlab.ui.control.Label
        vibration_threshold       matlab.ui.control.EditField
        text3_3                   matlab.ui.control.Label
        TXTPanel                  matlab.ui.container.Panel
        TXT_channel_data_scaling  matlab.ui.control.EditField
        text4_2                   matlab.ui.control.Label
        TXT_channeNames           matlab.ui.control.EditField
        text3_2                   matlab.ui.control.Label
        Panel_5                   matlab.ui.container.Panel
        ButtonGroup_Vib           matlab.ui.container.ButtonGroup
        Button_VibP               matlab.ui.control.RadioButton
        Button_VibM               matlab.ui.control.RadioButton
        Button_VibA               matlab.ui.control.RadioButton
        Switch_Disp               matlab.ui.control.Switch
        Panel_4                   matlab.ui.container.Panel
        Switch_Speed              matlab.ui.control.Switch
        Label_2                   matlab.ui.control.Label
        Edit_SpeedPts             matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        Panel_3                   matlab.ui.container.Panel
        edit_ZRange               matlab.ui.control.EditField
        text5                     matlab.ui.control.Label
        edit_NumPt                matlab.ui.control.EditField
        text4                     matlab.ui.control.Label
        edit_MaxLimit             matlab.ui.control.EditField
        text3                     matlab.ui.control.Label
        Panel_2                   matlab.ui.container.Panel
        edit_Speed                matlab.ui.control.EditField
        text7                     matlab.ui.control.Label
        text6                     matlab.ui.control.Label
        edit_ChnOrder             matlab.ui.control.EditField
        Panel                     matlab.ui.container.Panel
        WFSt                      matlab.ui.control.NumericEditField
        HzEditFieldLabel          matlab.ui.control.Label
        WFEd                      matlab.ui.control.NumericEditField
        HzEditField_2Label        matlab.ui.control.Label
        WFIt                      matlab.ui.control.NumericEditField
        HzEditField_3Label        matlab.ui.control.Label
        text8                     matlab.ui.control.Label
        edit_FFTRange             matlab.ui.control.EditField
        pushbutton_Return         matlab.ui.control.Button
        text2                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        FApp % Description
    end
    
    methods (Access = private)
        
        function tdmsInfo = SetData(app,tdmsInfo)
            %设置数据,读取模式，用于子程序初始化
            if nargin == 2
                app.edit_MaxLimit.Value = num2str(tdmsInfo.maxLimit);
                app.edit_ZRange.Value = num2str(tdmsInfo.zRange);
                str2 = mat2str(tdmsInfo.chnOrder);
                app.edit_ChnOrder.Value = str2(2:end-1);
                app.edit_NumPt.Value = num2str(tdmsInfo.numPt);
                app.edit_Speed.Value = num2str(tdmsInfo.speedChn);
                %20200814 增加FFT
                app.edit_FFTRange.Value = num2str(tdmsInfo.FFTRange);  %第1处
                
                %2022.4.14增加瀑布图
                app.WFSt.Value = tdmsInfo.WFSt;
                app.WFEd.Value = tdmsInfo.WFEd;
                app.WFIt.Value = tdmsInfo.WFIt;
                
                %2022.4.14增加速度曲线点数
                app.Edit_SpeedPts.Value = tdmsInfo.SpeedPts;
%                 print(tdmsInfo.SpeedSwitch)
                app.Switch_Speed.Value = tdmsInfo.SpeedSwitch;

                %2022.4.17振动曲线
%                 app.Switch_Vib.Value = tdmsInfo.Vib;
                if strcmp(tdmsInfo.Vib,'幅值') == 1
                    app.Button_VibA.Value = 1;
                end
                if strcmp(tdmsInfo.Vib,'平均值') == 1
                    app.Button_VibM.Value = 1;
                end              
                if strcmp(tdmsInfo.Vib,'峰峰值') == 1
                    app.Button_VibP.Value = 1;
                end             
                
                app.Switch_Disp.Value = tdmsInfo.Disp;


                %2023.12.26增加对TXT文件的支持
                app.TXT_channeNames.Value = tdmsInfo.TXT_channeNames; % 数据通道的定义
                app.TXT_channel_data_scaling.Value = tdmsInfo.TXT_channel_data_scaling;% 数据通道的缩放

                %故障诊断参数的设置
                app.vibration_threshold.Value = tdmsInfo.vibration_threshold;
                app.global_scanning_density.Value = tdmsInfo.global_scanning_density;  
                app.scanning_sensitivity.Value = scanning_sensitivity;


            %获取数据，mode = 2
            else
                maxLimit = str2double(app.edit_MaxLimit.Value);
                zRange = str2double(app.edit_ZRange.Value);
                chnOrder = str2num(app.edit_ChnOrder.Value);
                numPt = str2num(app.edit_NumPt.Value);
                speedChn = str2num(app.edit_Speed.Value);
                FFTRange = str2double(app.edit_FFTRange.Value);  %第2处
            
                %2022.4.14增加瀑布图
                WFSt= app.WFSt.Value;
                WFEd = app.WFEd.Value;
                WFIt = app.WFIt.Value;
                
                %2022.4.14增加速度曲线点数
                SpeedPts = app.Edit_SpeedPts.Value;
                SpeedSwitch = app.Switch_Speed.Value;
                
                %2022.4.17振动曲线
%                 Vib = app.Switch_Vib.Value;
                if app.Button_VibA.Value == 1
                    Vib = '幅值';
                end
                if app.Button_VibM.Value == 1
                    Vib = '平均值';
                end
                if app.Button_VibP.Value == 1
                    Vib = '峰峰值';
                end                
                
                Disp = app.Switch_Disp.Value;

                %2023.12.26增加对TXT文件的支持
                TXT_channeNames = app.TXT_channeNames.Value; % 数据通道的定义
                TXT_channel_data_scaling = app.TXT_channel_data_scaling.Value;  % 数据通道的缩放
                scanning_sensitivity = app.scanning_sensitivity.Value % 超过警戒阈值的点的百分比超过30%：存在疑似故障；；超过绝对阈值的点的百分比超过20%：存在绝对故障；

                %故障诊断参数的设置
                vibration_threshold = app.vibration_threshold.Value;
                global_scanning_density = app.global_scanning_density.Value; 
                scanning_sensitivity = app.scanning_sensitivity;
                
                tdmsInfo = struct('numPt',numPt,'maxLimit',maxLimit,'zRange',zRange,...
                    'chnOrder',chnOrder,'speedChn',speedChn,'FFTRange',FFTRange,...
                    'WFSt',WFSt,'WFEd',WFEd,'WFIt',WFIt,...
                    'SpeedPts',SpeedPts,'SpeedSwitch',SpeedSwitch,...
                    'Vib',Vib,'Disp',Disp,...
                    'TXT_channeNames',TXT_channeNames,'TXT_channel_data_scaling',TXT_channel_data_scaling, ...
                    'vibration_threshold',vibration_threshold,'global_scanning_density',global_scanning_density,'scanning_sensitivity',scanning_sensitivity);
                
             
                % 获取当前运行的 .m 文件的路径
                currentPath = fileparts(mfilename('fullpath'));                
                % 指定相对路径的文件夹
                relativeFolderPath = 'config';                
                % 构建完整的文件夹路径
                folderPath = fullfile(currentPath, relativeFolderPath);                
                % 如果文件夹不存在，则创建
                if ~exist(folderPath, 'dir')
                    mkdir(folderPath);
                end                
                % 构建完整的文件路径
                filePath = fullfile(folderPath, 'tdmsInfo_config.mat');                
                % 保存 tdmsInfo 结构为 .mat 文件（如果文件已经存在则覆盖）
                save(filePath, 'tdmsInfo', '-mat', '-v7.3');
                    
                app.pushbutton_Return.UserData = tdmsInfo;

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
           % 初始化：将主应用的引用存储在 FApp 属性中，并从主应用获取之前保存的设置数据。 
            app.FApp = mainapp;
            tdmsInfo = app.FApp.pushbutton_Setting.UserData;
            tdmsInfo = SetData(app,tdmsInfo);
        end

        % Button pushed function: pushbutton_Return
        function pushbutton_ReturnButtonPushed(app, event)
            SetData(app);
            app.FApp.pushbutton_Setting.UserData = app.pushbutton_Return.UserData;
             
            if isempty(app.FApp.pushbutton_Open.UserData) == 0    
                data = DrawOrbit2(app.FApp);
                InfoRefresh(app.FApp);
            end  
            delete(app);
        end

        % Value changed function: Switch_Speed
        function Switch_SpeedValueChanged(app, event)
            value = app.Switch_Speed.Value;
            if strcmp(value,'On')
                if isempty(app.edit_Speed.Value) == 1
                    msgbox('请先填写转速通道！');
                    app.Switch_Speed.Value = 'Off';
                end
            end
        end

        % Selection changed function: ButtonGroup_Vib
        function ButtonGroup_VibSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_Vib.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.text2 = uilabel(app.UIFigure);
            app.text2.Tag = 'text2';
            app.text2.Text = 'TDMS数据设置';
            app.pushbutton_Return = uibutton(app.UIFigure, 'push');
            app.pushbutton_Return.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_ReturnButtonPushed, true);
            app.pushbutton_Return.Tag = 'pushbutton_Return';
            app.pushbutton_Return.Text = '确认';
            app.edit_FFTRange = uieditfield(app.UIFigure, 'text');
            app.edit_FFTRange.Tag = 'edit_FFTRange';
            app.edit_FFTRange.Value = '1000';
            app.text8 = uilabel(app.UIFigure);
            app.text8.Tag = 'text8';
            app.text8.Text = 'FFT频率范围';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '瀑布图设置';
            app.HzEditField_3Label = uilabel(app.Panel);
            app.HzEditField_3Label.Text = '起始频率/Hz';
            app.WFIt = uieditfield(app.Panel, 'numeric');
            app.WFIt.Value = 1;
            app.HzEditField_2Label = uilabel(app.Panel);
            app.HzEditField_2Label.Text = '终止频率/Hz';
            app.WFEd = uieditfield(app.Panel, 'numeric');
            app.WFEd.Value = 1000;
            app.HzEditFieldLabel = uilabel(app.Panel);
            app.HzEditFieldLabel.Text = '间隔/Hz';
            app.WFSt = uieditfield(app.Panel, 'numeric');
            app.WFSt.Value = 1;
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '通道设置';
            app.edit_ChnOrder = uieditfield(app.Panel_2, 'text');
            app.edit_ChnOrder.Tag = 'edit_ChnOrder';
            app.edit_ChnOrder.Value = '4,5,2,3,6';
            app.text6 = uilabel(app.Panel_2);
            app.text6.Tag = 'text6';
            app.text6.Text = '振动通道顺序';
            app.text7 = uilabel(app.Panel_2);
            app.text7.Tag = 'text7';
            app.text7.Text = '转速通道';
            app.edit_Speed = uieditfield(app.Panel_2, 'text');
            app.edit_Speed.Tag = 'edit_Speed';
            app.edit_Speed.Value = '17';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '显示设置';
            app.text3 = uilabel(app.Panel_3);
            app.text3.Tag = 'text3';
            app.text3.Text = '坐标轴范围/V';
            app.edit_MaxLimit = uieditfield(app.Panel_3, 'text');
            app.edit_MaxLimit.Tag = 'edit_MaxLimit';
            app.edit_MaxLimit.Value = '5.0';
            app.text4 = uilabel(app.Panel_3);
            app.text4.Tag = 'text4';
            app.text4.Text = '数据块点数';
            app.edit_NumPt = uieditfield(app.Panel_3, 'text');
            app.edit_NumPt.Tag = 'edit_NumPt';
            app.edit_NumPt.Value = '8000';
            app.text5 = uilabel(app.Panel_3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Z横坐标范围/s';
            app.edit_ZRange = uieditfield(app.Panel_3, 'text');
            app.edit_ZRange.Tag = 'edit_ZRange';
            app.edit_ZRange.Value = '0.8';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = '转速设置';
            app.Label = uilabel(app.Panel_4);
            app.Label.Text = '速度曲线计算点数';
            app.Edit_SpeedPts = uieditfield(app.Panel_4, 'numeric');
            app.Edit_SpeedPts.Value = 100;
            app.Label_2 = uilabel(app.Panel_4);
            app.Label_2.Text = '使用转速通道';
            app.Switch_Speed = uiswitch(app.Panel_4, 'slider');
            app.Switch_Speed.ValueChangedFcn = createCallbackFcn(app, @Switch_SpeedValueChanged, true);
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_5.Title = '振动设置';
            app.Switch_Disp = uiswitch(app.Panel_5, 'slider');
            app.Switch_Disp.Items = {'单曲线', '多曲线一图'};
            app.Switch_Disp.Value = '多曲线一图';
            app.ButtonGroup_Vib = uibuttongroup(app.Panel_5);
            app.ButtonGroup_Vib.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_VibSelectionChanged, true);
            app.Button_VibA = uiradiobutton(app.ButtonGroup_Vib);
            app.Button_VibA.Text = '幅值';
            app.Button_VibA.Value = true;
            app.Button_VibM = uiradiobutton(app.ButtonGroup_Vib);
            app.Button_VibM.Text = '平均值';
            app.Button_VibP = uiradiobutton(app.ButtonGroup_Vib);
            app.Button_VibP.Text = '峰峰值';
            app.TXTPanel = uipanel(app.UIFigure);
            app.TXTPanel.Title = 'TXT文件读取设置';
            app.text3_2 = uilabel(app.TXTPanel);
            app.text3_2.Tag = 'text3';
            app.text3_2.Text = '数据通道定义';
            app.TXT_channeNames = uieditfield(app.TXTPanel, 'text');
            app.TXT_channeNames.Tag = 'edit_MaxLimit';
            app.TXT_channeNames.Value = 'EXC,X1,Y1,Z1,X2,Y2,PX1,NX1,PY1,NY1,PZ,NZ,PX2,NX2,PY2,NY2,time,speed,rotor_angle';
            app.text4_2 = uilabel(app.TXTPanel);
            app.text4_2.Tag = 'text4';
            app.text4_2.Text = '通道数据缩放';
            app.TXT_channel_data_scaling = uieditfield(app.TXTPanel, 'text');
            app.TXT_channel_data_scaling.Tag = 'edit_NumPt';
            app.TXT_channel_data_scaling.Value = '1/2048,1/32,1/32,1/32,1/32,1/32,1/256,1/256,1/256,1/256,1/256,1/256,1/256,1/256,1/256,1/256,1,1,1';
            app.Panel_6 = uipanel(app.UIFigure);
            app.Panel_6.Title = '故障诊断设置';
            app.text3_3 = uilabel(app.Panel_6);
            app.text3_3.Tag = 'text3';
            app.text3_3.Text = '振动阈值设置';
            app.vibration_threshold = uieditfield(app.Panel_6, 'text');
            app.vibration_threshold.Tag = 'edit_MaxLimit';
            app.vibration_threshold.Value = '0.25,0.85';
            app.text3_4 = uilabel(app.Panel_6);
            app.text3_4.Tag = 'text3';
            app.text3_4.Text = '全域扫描密度设置';
            app.global_scanning_density = uieditfield(app.Panel_6, 'text');
            app.global_scanning_density.Tag = 'edit_MaxLimit';
            app.global_scanning_density.Value = '100';
            app.text3_5 = uilabel(app.Panel_6);
            app.text3_5.Tag = 'text3';
            app.text3_5.Text = '扫描敏感度';
            app.scanning_sensitivity = uieditfield(app.Panel_6, 'text');
            app.scanning_sensitivity.Tag = 'edit_MaxLimit';
            app.scanning_sensitivity.Value = '0.3,0.8';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TDMSInfo3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end